classdef HPLCwithnewintegration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HPLCApp                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PDATab                          matlab.ui.container.Tab
        TabGroupPDA                     matlab.ui.container.TabGroup
        AllFilesTab                     matlab.ui.container.Tab
        LoadHPLCFileSetupxmlButton      matlab.ui.control.Button
        SaveHPLCFileSetupButton         matlab.ui.control.Button
        ExportChromLabelPDA             matlab.ui.control.Label
        ExportSpecLabelPDA              matlab.ui.control.Label
        ExportChromButtonPDA            matlab.ui.control.Button
        ExportSpectraButtonPDA          matlab.ui.control.Button
        AlignPeaksButtonPDA             matlab.ui.control.Button
        LoadFilesButtonPDA              matlab.ui.control.Button
        UITablePDA                      matlab.ui.control.Table
        UIAxesSpecPDA                   matlab.ui.control.UIAxes
        UIAxesChromPDA                  matlab.ui.control.UIAxes
        PLTab                           matlab.ui.container.Tab
        TabGroupPL                      matlab.ui.container.TabGroup
        AllFilesTab_2                   matlab.ui.container.Tab
        LoadHPLCFileSetupxmlButton_2    matlab.ui.control.Button
        SaveHPLCFileSetupButton_2       matlab.ui.control.Button
        ExportChromLabelPL              matlab.ui.control.Label
        ExportSpecLabelPL               matlab.ui.control.Label
        ExportChromButtonPL             matlab.ui.control.Button
        ExportSpectraButtonPL           matlab.ui.control.Button
        AlignPeaksButtonPL              matlab.ui.control.Button
        LoadFilesButtonPL               matlab.ui.control.Button
        UITablePL                       matlab.ui.control.Table
        UIAxesSpecPL                    matlab.ui.control.UIAxes
        UIAxesChromPL                   matlab.ui.control.UIAxes
        ConductivityTab                 matlab.ui.container.Tab
        TabGroupConductivity            matlab.ui.container.TabGroup
        AllFilesTab_3                   matlab.ui.container.Tab
        LoadHPLCFileSetupxmlButton_5    matlab.ui.control.Button
        SaveHPLCFileSetupButton_5       matlab.ui.control.Button
        ExportChromLabelConductivity    matlab.ui.control.Label
        ExportSpecLabelConductivity     matlab.ui.control.Label
        ExportChromButtonConductivity   matlab.ui.control.Button
        ExportSpectraButtonConductivity  matlab.ui.control.Button
        AlignPeaksButtonConductivity    matlab.ui.control.Button
        LoadFilesButtonConductivity     matlab.ui.control.Button
        UITableConductivity             matlab.ui.control.Table
        UIAxesSpecConductivity          matlab.ui.control.UIAxes
        UIAxesChromConductivity         matlab.ui.control.UIAxes
        RefractiveIndexTab              matlab.ui.container.Tab
        TabGroupRefractiveIndex         matlab.ui.container.TabGroup
        AllFilesRefractiveIndex         matlab.ui.container.Tab
        LoadHPLCFileSetupxmlButton_4    matlab.ui.control.Button
        SaveHPLCFileSetupButton_4       matlab.ui.control.Button
        ExportChromLabelRefractiveIndex  matlab.ui.control.Label
        ExportSpecLabelRefractiveIndex  matlab.ui.control.Label
        ExportChromButtonRefractiveIndex  matlab.ui.control.Button
        ExportSpectraButtonRefractiveIndex  matlab.ui.control.Button
        AlignPeaksButtonRefractiveIndex  matlab.ui.control.Button
        LoadFilesButtonRefractiveIndex  matlab.ui.control.Button
        UITableRefractiveIndex          matlab.ui.control.Table
        UIAxesSpecRefractiveIndex       matlab.ui.control.UIAxes
        UIAxesChromRefractiveIndex      matlab.ui.control.UIAxes
        ElectrochemicalTab              matlab.ui.container.Tab
        TabGroupElectrochemical         matlab.ui.container.TabGroup
        AllFilesTabElectrochemical      matlab.ui.container.Tab
        LoadHPLCFileSetupxmlButton_3    matlab.ui.control.Button
        SaveHPLCFileSetupButton_3       matlab.ui.control.Button
        ExportChromLabelElectrochemical  matlab.ui.control.Label
        ExportSpecLabelElectrochemical  matlab.ui.control.Label
        ExportChromButtonElectrochemical  matlab.ui.control.Button
        ExportSpectraButtonElectrochemical  matlab.ui.control.Button
        AlignPeaksButtonElectrochemical  matlab.ui.control.Button
        LoadFilesButtonElectrochemical  matlab.ui.control.Button
        UITableElectrochemical          matlab.ui.control.Table
        UIAxesSpecElectrochemical       matlab.ui.control.UIAxes
        UIAxesChromElectrochemical      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename % Selected file's name
        pathname % Path of selected file
        numfile % Number of files
        array % Holds all data here
        time % y-axis (time)
        wavelength % x-axis (wavelength)
        intensity % extract intensity data given time and wavelength axis
        tabs % names of tabs
        tabtitles % names of each tabs for the files on each detector
        selectedcell % holds the last selectedcell in either savespec or savechrom uitables
        YesButton % temporary button appearing for integration purposes
        NoButton % temporary button appearing for integration purposes
        EditBaselineButton % Button appears after integration to give option to edit baseline

        %Integration Properties
        Intdata % data passed through Integration to yesno function
        hline % baseline (roiline)
        line % baselines for each file (coefficients)
        peakData % peak limits indices for each file (peakData.(a){k}(peak,:) = [idxstart, idxend])
        inttype % valley-valley(1), dropdown(2), gauss(3)?
        baselines % Holds x and y points of baselines in cell structure where position n,1 is x points of peak n and n,2 is y points of peak n

        %Properties of Permenant ui members for each filetab
        %   each member is for a specific detector and tab called using
        %   (...).(det_name){file_idx}
        UIAxes3D % shows 3D data
        UIAxesSpec % shows spec data
        UIAxes % shows 3D map of data
        SelectedTimeEditFieldLabel
        SelectedTimeEditField % selected time within 3D data for spectra
        SelectedWavelengthEditFieldLabel
        UITableChrom % holds integration information for chromatogram
        TimeShiftEditFieldLabel
        TimeShiftEditField % the timeshift as accomplished through align peaks button will shift all time data for that file
        ResetTimeshiftButton % resets timeshift to 0
        SaveSpectraButton % to save a spectra plot
        SaveChromatogramButton % to save a chromatogram plot
        SaveSpec % table containing saved spectra plots
        SaveChrom % table containing saved chromatogram plots
        keepspec % determine if the previous specra must be kept
        keepchrom % these become false true when a plot is saved using save spectra or save chromatogram buttons
        ExportSpectraButton % to export data of all plots on spectra
        ExportChromatogramButton % to export data of all plots on chromatogram
        speclow % lower axis limit on spectra plot
        specupp % upper axis limit on spectra plot
        SpectraAxisLimitsLabel
        chromlow % lower axis limit on chromatogram plot
        chromupp % upper axis limit on chromatogram plot
        ChromatogramAxisLimitsLabel
        Intlabel % Integration label will appear with user instructions for integration and other functions
        IntegrateChromatogramButton % button for integrating the chrom
        ExportIntegrationButton % button to export excel file of integration info and baseline points
        timeROILine % Line denoting time cuts
        waveROILine % Line denoting wavlength cuts
        SaveParamButton % save parameters of how current file is processed
        LoadParamButton % load processing of saved parameters
        SilentProcess % load processing of saved parameters directly to exported excel format
        Label % Used to label the type of data (scan v chromatogram only), can be useful when executing functions and needing to distinguish
        AdvancedIntTool % opens seperate app
    end
    
    properties (Access = public)
        % These public properties are so data can be passed on to Align peaks app
        % They are structure properties for each filetab
        UIAxesChrom % shows Chrom data
        SelectedWavelengthEditField % Selected wavelength within 3D data for chromatogram
    end
    
    methods (Access = private)
        % Functions to choose/set colours
        function colors = distinguishable_colors(app, n_colors,bg,func)
            % DISTINGUISHABLE_COLORS: pick colors that are maximally perceptually distinct
            %
            % When plotting a set of lines, you may want to distinguish them by color.
            % By default, Matlab chooses a small set of colors and cycles among them,
            % and so if you have more than a few lines there will be confusion about
            % which line is which. To fix this problem, one would want to be able to
            % pick a much larger set of distinct colors, where the number of colors
            % equals or exceeds the number of lines you want to plot. Because our
            % ability to distinguish among colors has limits, one should choose these
            % colors to be "maximally perceptually distinguishable."
            %
            % This function generates a set of colors which are distinguishable
            % by reference to the "Lab" color space, which more closely matches
            % human color perception than RGB. Given an initial large list of possible
            % colors, it iteratively chooses the entry in the list that is farthest (in
            % Lab space) from all previously-chosen entries. While this "greedy"
            % algorithm does not yield a global maximum, it is simple and efficient.
            % Moreover, the sequence of colors is consistent no matter how many you
            % request, which facilitates the users' ability to learn the color order
            % and avoids major changes in the appearance of plots when adding or
            % removing lines.
            %
            % Syntax:
            %   colors = distinguishable_colors(n_colors)
            % Specify the number of colors you want as a scalar, n_colors. This will
            % generate an n_colors-by-3 matrix, each row representing an RGB
            % color triple. If you don't precisely know how many you will need in
            % advance, there is no harm (other than execution time) in specifying
            % slightly more than you think you will need.
            %
            %   colors = distinguishable_colors(n_colors,bg)
            % This syntax allows you to specify the background color, to make sure that
            % your colors are also distinguishable from the background. Default value
            % is white. bg may be specified as an RGB triple or as one of the standard
            % "ColorSpec" strings. You can even specify multiple colors:
            %     bg = {'w','k'}
            % or
            %     bg = [1 1 1; 0 0 0]
            % will only produce colors that are distinguishable from both white and
            % black.
            %
            %   colors = distinguishable_colors(n_colors,bg,rgb2labfunc)
            % By default, distinguishable_colors uses the image processing toolbox's
            % color conversion functions makecform and applycform. Alternatively, you
            % can supply your own color conversion function.
            %
            % Example:
            %   c = distinguishable_colors(25);
            %   figure
            %   image(reshape(c,[1 size(c)]))
            %
            % Example using the file exchange's 'colorspace':
            %   func = @(x) colorspace('RGB->Lab',x);
            %   c = distinguishable_colors(25,'w',func);

            % Copyright 2010-2011 by Timothy E. Holy

            % Parse the inputs
            if (nargin < 3)
                bg = [1 1 1];  % default white background
            else
                if iscell(bg)
                    % User specified a list of colors as a cell aray
                    bgc = bg;
                    for i = 1:length(bgc)
                        bgc{i} = parsecolor(app,bgc{i});
                    end
                    bg = cat(1,bgc{:});
                else
                    % User specified a numeric array of colors (n-by-3)
                    bg = parsecolor(app, bg);
                end
            end

            % Generate a sizable number of RGB triples. This represents our space of
            % possible choices. By starting in RGB space, we ensure that all of the
            % colors can be generated by the monitor.
            n_grid = 30;  % number of grid divisions along each axis in RGB space
            x = linspace(0,1,n_grid);
            [R,G,B] = ndgrid(x,x,x);
            rgb = [R(:) G(:) B(:)];
            if (n_colors > size(rgb,1)/3)
                error('You can''t readily distinguish that many colors');
            end

            % Convert to Lab color space, which more closely represents human
            % perception
            if (nargin > 3)
                lab = func(rgb);
                bglab = func(bg);
            else
                C = makecform('srgb2lab');
                lab = applycform(rgb,C);
                bglab = applycform(bg,C);
            end

            % If the user specified multiple background colors, compute distances
            % from the candidate colors to the background colors
            mindist2 = inf(size(rgb,1),1);
            for i = 1:size(bglab,1)-1
                dX = bsxfun(@minus,lab,bglab(i,:)); % displacement all colors from bg
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
            end

            % Iteratively pick the color that maximizes the distance to the nearest
            % already-picked color
            colors = zeros(n_colors,3);
            lastlab = bglab(end,:);   % initialize by making the "previous" color equal to background
            for i = 1:n_colors
                dX = bsxfun(@minus,lab,lastlab); % displacement of last from all colors on list
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
                [~,index] = max(mindist2);  % find the entry farthest from all previously-chosen colors
                colors(i,:) = rgb(index,:);  % save for output
                lastlab = lab(index,:);  % prepare for next iteration
            end
        end

        function c = parsecolor(app,s)
            if ischar(s)
                c = colorstr2rgb(app,s);
            elseif isnumeric(s) && size(s,2) == 3
                c = s;
            else
                error('MATLAB:InvalidColorSpec','Color specification cannot be parsed.');
            end
        end

        function c = colorstr2rgb(~,c)
            % Convert a color string to an RGB value.
            % This is cribbed from Matlab's whitebg function.
            % Why don't they make this a stand-alone function?
            rgbspec = [1 0 0;0 1 0;0 0 1;1 1 1;0 1 1;1 0 1;1 1 0;0 0 0];
            cspec = 'rgbwcmyk';
            k = find(cspec==c(1));
            if isempty(k)
                error('MATLAB:InvalidColorString','Unknown color string.');
            end
            if k~=3 || length(c)==1
                c = rgbspec(k,:);
            elseif length(c)>2
                if strcmpi(c(1:3),'bla')
                    c = [0 0 0];
                elseif strcmpi(c(1:3),'blu')
                    c = [0 0 1];
                else
                    error('MATLAB:UnknownColorString', 'Unknown color string.');
                end
            end
        end

        function drawlines(app, k, a) % Draws ROILines as part of tab set up
%             a = app.TabGroup.SelectedTab.Title; % Detector name
            % Get center positions that time and wavelength lines will be at.
            timepos = app.time.(a){k}(round(length(app.time.(a){k})/2));  % Time at center index
            wavepos = app.wavelength.(a){k}(round(length(app.wavelength.(a){k})/2)); % Wavelength at center index
            % Draw time cut by creating an ROI line and adding a listener to create a callback when user moves line
            app.timeROILine.(a){k} = images.roi.Line(app.UIAxes.(a){k}, "Position", [app.UIAxes.(a){k}.XLim(1)-1, timepos; app.UIAxes.(a){k}.XLim(2)*1.01, timepos], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0]);
            addlistener(app.timeROILine.(a){k}, 'ROIMoved', @(src, event)TLMoved(app, event, k));
            % Draw wavelength cut by creating an ROI line and adding a listener to create a callback when user moves line
            app.waveROILine.(a){k} = images.roi.Line(app.UIAxes.(a){k}, "Position", [wavepos, app.UIAxes.(a){k}.YLim(1)-1; wavepos, app.UIAxes.(a){k}.YLim(2)*1.01], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0]);
            addlistener(app.waveROILine.(a){k}, 'ROIMoved', @(src, event)WLMoved(app, event, k));
        end
        
        function TLMoved(app, event, k) % The time callback that occurs as result of listener created on ROI Line in draw lines
            switch event.EventName % While this switch even should not be neccesary...Errors always resulted without it
                case 'ROIMoved'
                    a = app.TabGroup.SelectedTab.Title; % Detector name
                    shft = app.TimeShiftEditField.(a){k}.Value; % Time shift if applied, else 0

                    % Update time using index

                    % To update working plot determine if current working plot should be replaced
                    if app.keepspec.(a){k} == 0 % Keep spec is false(0) as default
                        delete(app.UIAxesSpec.(a){k}.Children(1)) % working plot is deleted unless saved
                    else % Keep spec becomes true(1) after a plot is saved (SaveSpectra callback) and the working plot will not be deleted, but will no longer be the working plot
                        app.keepspec.(a){k} = 0; % Reset keep spec to default false(0)
                    end
                    % Get closest value
                    [~, idx] = (min(abs(app.timeROILine.(a){k}.Position(3)-app.time.(a){k}-shft))); % Index of time calculated as closest time val to roi position (min. difference between position and possible datapoints)
                    value = app.time.(a){k}(idx); % true time value according to closest value to user selection
                    app.timeROILine.(a){k}.Position(3) = value; % Fix ROILine position
                    app.timeROILine.(a){k}.Position(4) = value;
                    app.SelectedTimeEditField.(a){k}.Value = value; % update time edit field
                    app.(strcat('UITable', a)).Data{k,3} = value; % update all files table
                    plot(app.UIAxesSpec.(a){k}, app.wavelength.(a){k}, app.intensity.(a){k}(idx,:), 'DisplayName', string(value)); % Plot new working plot
                    SetColor(app, app.UIAxesSpec.(a){k})
                    app.(strcat('UIAxesSpec', a)).Children(end-k+1).YData = app.intensity.(a){k}(idx,:); % Update plot on all files page
                    SetColor(app, app.(strcat('UIAxesSpec', a)))
            end
        end

        function WLMoved(app, event, k) % The wavelength callback that occurs as result of listener created on ROI Line in draw lines
            switch event.EventName % While this switch even should not be neccesary...Errors always resulted without it
                case 'ROIMoved'
                    a = app.TabGroup.SelectedTab.Title; % Detector name
                    shft = app.TimeShiftEditField.(a){k}.Value; % Time shift if applied, else 0

                    % Update wavelength using index of wavelength

                    % To update working plot determine if current working plot must be replaced or will be saved
                    if app.keepchrom.(a){k} == 0 % Keep chrom false(0) by default
                        delete(app.UIAxesChrom.(a){k}.Children(1)) % Delete working plot to be replaced
                    else % Keep chrom becomes true(1) after user saves chromatogram (SaveChromatogram Callback). The recent plot will no longer be the working plot
                        app.keepchrom.(a){k} = 0; % Reset keep chrom bool to false(0)
                    end
                    % Get closest value
                    [~,idx] = min(abs(app.waveROILine.(a){k}.Position(1)-app.wavelength.(a){k})); % Get index for closest wavelength to position
                    value = app.wavelength.(a){k}(idx);
                    app.waveROILine.(a){k}.Position(1) = value; % RoiLine
                    app.waveROILine.(a){k}.Position(2) = value;
                    app.SelectedWavelengthEditField.(a){k}.Value = value; % update wavelength edit field
                    app.(strcat('UITable', a)).Data{k,4} = value; % Update wavelength on all files tab
                    plot(app.UIAxesChrom.(a){k},app.time.(a){k} + shft, app.intensity.(a){k}(:,idx), 'DisplayName', string(value)); % update working plot
                    SetColor(app, app.UIAxesChrom.(a){k})
                    app.(strcat('UIAxesChrom', a)).Children(end-k+1).YData = app.intensity.(a){k}(:, idx); % Update working plot on all files tab
                    SetColor(app, app.(strcat('UIAxesChrom', a)))
            end
        end

        function tabsetup(app, k, a) % Occurs during Load file loop to set up each tab one file at a time
            tabname = app.(strcat('TabGroup', a)).Children(k+1); % name of tab to edit
            % Determine Type of Data (scan v chrom) with the width of
            % intensity data as shown in the if statement
            if width(app.intensity.(a){k}) > 3 % scan of 3D data
                % Create panels for organizing page into left, spec, and chrom panels;
                specpan = uipanel(tabname);
                specpan.Position = [393 450 532 216];
                specpan.AutoResizeChildren = 'on';
                chrompan = uipanel(tabname);
                chrompan.Position = [393 235 532 216];
                chrompan.AutoResizeChildren = 'on';
                leftpan = uipanel(tabname);
                leftpan.Position = [1, 0, 393, 666];
                leftpan.AutoResizeChildren = 'on';

                % ------------The next sets of code will create app components ------------- %
    
                % Create Label:
                % label in top left corner to distinguish data file type
                % this one is a scan (3D data)
                app.Label.(a){k} = uilabel(leftpan);
                app.Label.(a){k}.BackgroundColor = [1 1 1];
                app.Label.(a){k}.HorizontalAlignment = 'center';
                app.Label.(a){k}.FontWeight = 'bold';
                app.Label.(a){k}.Position = [5 615 145 40];
                app.Label.(a){k}.Text = 'Scan Data';

                % Create UIAxes3D : 
                % 3D visual view of data in top left to give user overall idea of data
                app.UIAxes3D.(a){k} = uiaxes(leftpan);
                xlabel(app.UIAxes3D.(a){k}, 'Wavelength')
                ylabel(app.UIAxes3D.(a){k}, 'Time')
                zlabel(app.UIAxes3D.(a){k}, 'Intensity')
                app.UIAxes3D.(a){k}.Box = 'on';
                app.UIAxes3D.(a){k}.Position = [155 475 220 190];
    
                % Create UIAxesSpec :
                % Spectra cuts of data at user selected time as wavelength v intensity
                app.UIAxesSpec.(a){k} = uiaxes(specpan);
                title(app.UIAxesSpec.(a){k}, 'Spectra')
                xlabel(app.UIAxesSpec.(a){k}, 'Wavelength')
                ylabel(app.UIAxesSpec.(a){k}, 'Intensity')
                legend(app.UIAxesSpec.(a){k})
                app.UIAxesSpec.(a){k}.Box = 'on';
                app.UIAxesSpec.(a){k}.Position = [0 0 376 213]; 
    
                % Create UIAxesChrom :
                % Chromatogram cuts of data at user selected wavelength as time v intensity
                app.UIAxesChrom.(a){k} = uiaxes(chrompan);
                title(app.UIAxesChrom.(a){k}, 'Chromatogram')
                xlabel(app.UIAxesChrom.(a){k}, 'Time')
                ylabel(app.UIAxesChrom.(a){k}, 'Intensity')
                legend(app.UIAxesChrom.(a){k})
                app.UIAxesChrom.(a){k}.Box = 'on';
                app.UIAxesChrom.(a){k}.Position = [0 0 376 213];
    
                % Create UIAxes :
                % Axes holding contour plot of 3D data with ROI lines, so
                % user can select and visualize spec and chrom cuts of data
                app.UIAxes.(a){k} = uiaxes(leftpan);
                xlabel(app.UIAxes.(a){k}, 'Wavelength')
                ylabel(app.UIAxes.(a){k}, 'Time')
                zlabel(app.UIAxes.(a){k}, 'Intensity')
                app.UIAxes.(a){k}.Box = 'on';
                app.UIAxes.(a){k}.Position = [1 1 370 370];
                app.UIAxes.(a){k}.Interactions = [];
                app.UIAxes.(a){k}.PlotBoxAspectRatio = [1,1,1];
                
    
                % Create SelectedTimeEditField :
                % Numeric edit field to control time of working spectra
                app.SelectedTimeEditField.(a){k} = uieditfield(tabname, 'numeric');
                app.SelectedTimeEditField.(a){k}.ValueChangedFcn = createCallbackFcn(app, @TimeROIChange, true);
                app.SelectedTimeEditField.(a){k}.Position = [174 398 55 22];
                % Create SelectedTimeEditFieldLabel :
                app.SelectedTimeEditFieldLabel.(a){k} = uilabel(tabname);
                app.SelectedTimeEditFieldLabel.(a){k}.HorizontalAlignment = 'right';
                app.SelectedTimeEditFieldLabel.(a){k}.Position = [44 398 81 22];
                app.SelectedTimeEditFieldLabel.(a){k}.Text = 'Selected Time';
    
                % Create SelectedWavelengthEditField :
                % Numeric edit field to control wavelength of working chromatogram
                app.SelectedWavelengthEditField.(a){k} = uieditfield(tabname, 'numeric');
                app.SelectedWavelengthEditField.(a){k}.ValueChangedFcn = createCallbackFcn(app, @WavelengthROIChange, true);
                app.SelectedWavelengthEditField.(a){k}.Position = [174 368 55 22];
                % Create SelectedWavelengthEditFieldLabel
                app.SelectedWavelengthEditFieldLabel.(a){k} = uilabel(tabname);
                app.SelectedWavelengthEditFieldLabel.(a){k}.HorizontalAlignment = 'right';
                app.SelectedWavelengthEditFieldLabel.(a){k}.Position = [43 368 118 22];
                app.SelectedWavelengthEditFieldLabel.(a){k}.Text = 'Selected Wavelength';
    
                % Create UITableChrom :
                % Each tab will have table to contain saved Integration data for chromatograms
                app.UITableChrom.(a){k} = uitable(tabname);
                app.UITableChrom.(a){k}.ColumnName = {'Peak Time'; 'At Wavelength'; 'Area'; 'Height'; 'Click to edit Baseline'};
                app.UITableChrom.(a){k}.RowName = {}; % Remove row labels default
                app.UITableChrom.(a){k}.Position = [393 0 532 138];
                app.UITableChrom.(a){k}.Data = {}; % Initialize UITable data to be of datatype cell
                app.UITableChrom.(a){k}.CellSelectionCallback = createCallbackFcn(app, @EditBaseline, true);
                % Initialize these integration variables
                % as empty arrays to prevent errors with advanced integration app
                app.peakData.(a){k} = []; 
                app.line.(a){k} = [];
                app.inttype.(a){k} = [];
                app.baselines.(a){k} = {};
    
                % Create TimeShiftEditField : 
                % Non editable numeric field to hold value of any
                % timeshift(default 0) that was created from align peaks app
                app.TimeShiftEditField.(a){k} = uieditfield(tabname, 'numeric');
                app.TimeShiftEditField.(a){k}.Editable = 'off';
                app.TimeShiftEditField.(a){k}.Position = [174 427 55 22];
%                 app.TimeShiftEditField.(a){k}.A

                % Create TimeShiftEditFieldLabel
                app.TimeShiftEditFieldLabel.(a){k} = uilabel(tabname);
                app.TimeShiftEditFieldLabel.(a){k}.HorizontalAlignment = 'right';
                app.TimeShiftEditFieldLabel.(a){k}.Position = [44 427 59 22];
                app.TimeShiftEditFieldLabel.(a){k}.Text = 'Time Shift';
    
                % Create ResetTimeshiftButton :
                % Used to reset timeshift as created by align peaks app
                app.ResetTimeshiftButton = uibutton(tabname, 'push');
                app.ResetTimeshiftButton.ButtonPushedFcn = createCallbackFcn(app, @timeshiftzero, true);
                app.ResetTimeshiftButton.Position = [235 427 136 22];
                app.ResetTimeshiftButton.Text = 'Reset Timeshift';
    
                % Create SaveSpectraButton : 
                % To compare different Spectra slices from same file, user
                % can save a spectra plot
                app.SaveSpectraButton.(a){k} = uibutton(specpan, 'push'); % not tabname put inside spectrapanel
                app.SaveSpectraButton.(a){k}.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectra, true);
                app.SaveSpectraButton.(a){k}.Position = [375 130 154 22]; % [768 580 154 22] but spectra is 393,450,532,216
                app.SaveSpectraButton.(a){k}.Text = 'Save Spectra';
    
                % Create ExportSpectraButton :
                % Saved spectra datapoints can be exported to .mat file
                app.ExportSpectraButton.(a){k} = uibutton(specpan, 'push');
                app.ExportSpectraButton.(a){k}.ButtonPushedFcn = createCallbackFcn(app, @ExportSpec, true);
                app.ExportSpectraButton.(a){k}.Position = [375 99 154 22];
                app.ExportSpectraButton.(a){k}.Text = 'Export Spectra';
    
                % Create SaveSpec :
                % Table to help user see the time each saved spectra is at
                % and adds ability to delete saved plots
                app.SaveSpec.(a){k} = uitable(specpan);
                app.SaveSpec.(a){k}.ColumnName = {'Time'};
                app.SaveSpec.(a){k}.RowName = {};
                app.SaveSpec.(a){k}.Position = [376 0 156 92];
                app.SaveSpec.(a){k}.SelectionType = 'row';
                app.SaveSpec.(a){k}.KeyReleaseFcn = createCallbackFcn(app, @deleteplot, true); % deletes a saved plot
                app.SaveSpec.(a){k}.CellSelectionCallback = createCallbackFcn(app, @getselectedcell, true); % Gets index for deleteplot callback
    
                % Create specupp & speclow : 
                % Numeric editfields can help user adjust X limits of
                % Spectra plot
                app.specupp.(a){k} = uieditfield(specpan, 'numeric');
                app.specupp.(a){k}.Position = [461 162 68 22];
                app.specupp.(a){k}.ValueChangedFcn = createCallbackFcn(app, @EditAxisLims, true);
                app.speclow.(a){k} = uieditfield(specpan, 'numeric');
                app.speclow.(a){k}.Position = [375 162 68 22]; 
                app.speclow.(a){k}.ValueChangedFcn = createCallbackFcn(app, @EditAxisLims, true);
                % Create SpectraAxisLimitsLabel
                app.SpectraAxisLimitsLabel.(a){k} = uilabel(specpan);
                app.SpectraAxisLimitsLabel.(a){k}.Position = [375 183 154 22]; 
                app.SpectraAxisLimitsLabel.(a){k}.Text = 'Spectra Axis Limits';
    
                % Create SaveChromatogramButton : 
                % To compare different Chromatogram slices from same file, user
                % can save a chromatogram plot
                app.SaveChromatogramButton.(a){k} = uibutton(chrompan, 'push');
                app.SaveChromatogramButton.(a){k}.ButtonPushedFcn = createCallbackFcn(app, @SaveChromatogram, true);
                app.SaveChromatogramButton.(a){k}.Position = [375 133 154 22];
                app.SaveChromatogramButton.(a){k}.Text = 'Save Chromatogram';

                % Create ExportChromatogramButton :
                % Saved chromatogram datapoints can be exported to .mat file
                app.ExportChromatogramButton.(a){k} = uibutton(chrompan, 'push');
                app.ExportChromatogramButton.(a){k}.ButtonPushedFcn = createCallbackFcn(app, @ExportChrom, true);
                app.ExportChromatogramButton.(a){k}.Position = [375 102 154 22]; 
                app.ExportChromatogramButton.(a){k}.Text = 'Export Chromatogram';
    
                % Create SaveChrom :
                % Table to help user see the time each saved chromatogram is at
                % and adds ability to delete saved plots
                app.SaveChrom.(a){k} = uitable(chrompan);
                app.SaveChrom.(a){k}.ColumnName = {'Wavelength'};
                app.SaveChrom.(a){k}.RowName = {};
                app.SaveChrom.(a){k}.Position = [376 0 156 92];
                app.SaveChrom.(a){k}.SelectionType = 'row';
                app.SaveChrom.(a){k}.KeyReleaseFcn = createCallbackFcn(app, @deleteplot, true); % Deletes a saved plot      
                app.SaveChrom.(a){k}.CellSelectionCallback = createCallbackFcn(app, @getselectedcell, true); % Gets index for deleteplot callback
    
                % Create chromup & chromlow : 
                % Numeric editfields can help user adjust X limits of
                % Chromatogram plot
                app.chromupp.(a){k} = uieditfield(chrompan, 'numeric');
                app.chromupp.(a){k}.Position = [461 164 68 22];
                app.chromupp.(a){k}.ValueChangedFcn = createCallbackFcn(app, @EditAxisLims, true);
                app.chromlow.(a){k} = uieditfield(chrompan, 'numeric');
                app.chromlow.(a){k}.Position = [375 164 68 22];
                app.chromlow.(a){k}.ValueChangedFcn = createCallbackFcn(app, @EditAxisLims, true);
                % Create ChromatogramAxisLimitsLabel
                app.ChromatogramAxisLimitsLabel.(a){k} = uilabel(chrompan);
                app.ChromatogramAxisLimitsLabel.(a){k}.Position = [375 185 154 22];
                app.ChromatogramAxisLimitsLabel.(a){k}.Text = 'Chromatogram Axis Limits';
    
                % Create Intlabel :
                % Label will become visible when information/instructions
                % need to be communicated mostly for integration instructions
                app.Intlabel.(a){k} = uilabel(tabname);
                app.Intlabel.(a){k}.FontWeight = 'bold';
                app.Intlabel.(a){k}.Visible = 'off';
                app.Intlabel.(a){k}.WordWrap = 'on';
                app.Intlabel.(a){k}.Position = [394 189 357 44];
                app.Intlabel.(a){k}.Text = 'This Label Will be for information on Integration';
                app.Intlabel.(a){k}.BackgroundColor = 'w';
    
                % Create IntegrateChromatogramButton :
                % Integrate a single peak on working chromatogram by user
                % selecting the x limits of the peak
                app.IntegrateChromatogramButton = uibutton(tabname, 'push');
                app.IntegrateChromatogramButton.ButtonPushedFcn = createCallbackFcn(app, @Integrate, true);
                app.IntegrateChromatogramButton.Position = [768 206 154 22];
                app.IntegrateChromatogramButton.Text = 'Integrate Chromatogram';

                % Create ExportIntegrationButton:
                % Export Integration to .mat file containing structure with 
                % info from UITable and baseline XY Points
                app.ExportIntegrationButton = uibutton(tabname, 'push');
                app.ExportIntegrationButton.ButtonPushedFcn = createCallbackFcn(app, @ExportIntegration, true);
                app.ExportIntegrationButton.Position = [768 146 154 22];
                app.ExportIntegrationButton.Text = 'Export Integration';

                % Create  AdvancedIntTool: 
                % Button to activate Advanced Integration App
                app.AdvancedIntTool = uibutton(tabname, 'push');
                app.AdvancedIntTool.ButtonPushedFcn = createCallbackFcn(app, @AdvancedIntegrate, true); % PeakIntegrator(app, app.TabGroup.SelectedTab.Title, find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a))));
                app.AdvancedIntTool.Position = [768 176 154 22];
                app.AdvancedIntTool.Text = 'Advanced Integration Tool';
    
                % Create SaveProcessingParameters :
                % For user to be able to replicate same processing
                % parameters in future, params structure can be saved as .xml
                app.SaveParamButton = uibutton(tabname, 'push');
                app.SaveParamButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParam, true);
                app.SaveParamButton.Position = [235 397 136 22];
                app.SaveParamButton.Text = 'Save Parameters';

                % Create LoadProcessingParameters :
                % Load params as saved by SaveParamButton (.xml of Params structure)
                app.LoadParamButton = uibutton(tabname, 'push');
                app.LoadParamButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParam, true);
                app.LoadParamButton.Position = [235 367 136 22];
                app.LoadParamButton.Text = 'Load Parameters';
    
                % Create SilentProcess : 
                % User can process other files using same parameters as
                % current file and export information in .mat files
                app.SilentProcess = uibutton(leftpan, 'push');
                app.SilentProcess.ButtonPushedFcn = createCallbackFcn(app, @SilentProcessing, true);
                app.SilentProcess.Position = [5 555 145 40];
                app.SilentProcess.BackgroundColor = [0 0 1];
                app.SilentProcess.FontColor = [1 1 1];
                app.SilentProcess.Text = 'Silent Process Files';
                
                % --------------The next set of code will populate the UI elements put in place------------- %
                
                % Plot 3D data
                contour(app.UIAxes.(a){k}, app.wavelength.(a){k}, app.time.(a){k}, app.intensity.(a){k}); % plot for selecting slices
                mesh(app.UIAxes3D.(a){k}, app.wavelength.(a){k}, app.time.(a){k}, app.intensity.(a){k}); % Plot for visual of 3D data

                % Get index of time and wavelength cuts
                tm = round(length(app.time.(a){k})/2); % find center of time
                wl = round(length(app.wavelength.(a){k})/2); % find center of wavelength

                % Function to create ROILines
                drawlines(app, k, a) 

                % Plot Chromatograms
                plot(app.UIAxesChrom.(a){k}, app.time.(a){k}, app.intensity.(a){k}(:, wl), 'DisplayName', string(app.wavelength.(a){k}(wl))); % plot chrom for file tab
                hold(app.UIAxesChrom.(a){k}, "on") % we want data to stay as changes are made
                SetColor(app, app.UIAxesChrom.(a){k})
                app.keepchrom.(a){k} = 0; % set boolean (changes when plot is saved) 
                app.UIAxesChrom.(a){k}.Legend.Title.String = 'Wavelength';

                plot(app.(strcat('UIAxesChrom', a)), app.time.(a){k}, app.intensity.(a){k}(:, wl)) % plot chrom for allfiles
                hold(app.(strcat('UIAxesChrom', a)), "on")
                legend(app.(strcat('UIAxesChrom', a)), "String", app.tabtitles.(a))
                SetColor(app, app.(strcat('UIAxesChrom', a)))

                % Plot Spectras
                plot(app.UIAxesSpec.(a){k}, app.wavelength.(a){k}, app.intensity.(a){k}(tm, :), 'DisplayName', string(app.time.(a){k}(tm))); % plot spec for file tab
                hold(app.UIAxesSpec.(a){k}, "on") % we want data to stay as changes are made
                SetColor(app, app.UIAxesSpec.(a){k})
                app.keepspec.(a){k} = 0; % set boolean (changes when plot is saved)
                app.UIAxesSpec.(a){k}.Legend.Title.String = 'Time';

                plot(app.(strcat('UIAxesSpec', a)), app.wavelength.(a){k}, app.intensity.(a){k}(tm, :)) % plot spec for allfiles
                hold(app.(strcat('UIAxesSpec', a)), "on")
                legend(app.(strcat('UIAxesSpec', a)), "String", app.tabtitles.(a))
                SetColor(app, app.(strcat('UIAxesSpec', a)))


                % Set up Edit Fields
                app.SelectedTimeEditField.(a){k}.Value = app.time.(a){k}(tm);
                app.SelectedTimeEditField.(a){k}.Limits = [min(app.time.(a){k}), max(app.time.(a){k})];
                app.SelectedWavelengthEditField.(a){k}.Value = app.wavelength.(a){k}(wl);
                app.SelectedWavelengthEditField.(a){k}.Limits = [min(app.wavelength.(a){k}), max(app.wavelength.(a){k})];
                app.(strcat('UITable', a)).Data(k,3:4) = [{app.time.(a){k}(tm)}, {app.wavelength.(a){k}(wl)}]; % For all files page
                app.chromlow.(a){k}.Value = app.UIAxesChrom.(a){k}.XLim(1);
                app.chromupp.(a){k}.Value = app.UIAxesChrom.(a){k}.XLim(2);
                app.speclow.(a){k}.Value = app.UIAxesSpec.(a){k}.XLim(1);
                app.specupp.(a){k}.Value = app.UIAxesSpec.(a){k}.XLim(2);

            else % Chrom data only
                
                % ------------The next sets of code will create app components ------------- %
    
                % Create Label:
                % label in top left corner to distinguish data file type
                % this one is chromatogram only data
                app.Label.(a){k} = uilabel(tabname);
                app.Label.(a){k}.BackgroundColor = [1 1 1];
                app.Label.(a){k}.HorizontalAlignment = 'center';
                app.Label.(a){k}.FontWeight = 'bold';
                app.Label.(a){k}.Position = [22 625 231 31];
                app.Label.(a){k}.Text = 'Chromatogram Only Data';

                % Create UIAxesChrom :
                % Axes to contain chromatogram of file data
                app.UIAxesChrom.(a){k} = uiaxes(tabname);
                title(app.UIAxesChrom.(a){k}, 'Chromatogram')
                xlabel(app.UIAxesChrom.(a){k}, 'Time')
                ylabel(app.UIAxesChrom.(a){k}, 'Intensity')
                app.UIAxesChrom.(a){k}.Box = 'on';
                app.UIAxesChrom.(a){k}.Position = [255 300 680 351];
    
                % Create TimeShiftEditField :
                % Uneditable numeric fild to hold timeshift value as
                % changerd by align peaks app
                app.TimeShiftEditField.(a){k} = uieditfield(tabname, 'numeric');
                app.TimeShiftEditField.(a){k}.Editable = 'off';
                app.TimeShiftEditField.(a){k}.Position = [134 590 108 22];
                app.TimeShiftEditField.(a){k}.Value = 0;
                % Create TimeShiftEditFieldLabel
                app.TimeShiftEditFieldLabel.(a){k} = uilabel(tabname);
                app.TimeShiftEditFieldLabel.(a){k}.Position = [22 590 59 22];
                app.TimeShiftEditFieldLabel.(a){k}.Text = 'Time Shift';

                % Create chromlow & chromupp
                % Numeric edit field to aid user in adjusting lower and
                % upper chromatogram limits, repectively
                app.chromlow.(a){k} = uieditfield(tabname, 'numeric');
                app.chromlow.(a){k}.Position = [134 525 50 22];
                app.chromlow.(a){k}.ValueChangedFcn = createCallbackFcn(app, @EditAxisLims, true);
                app.chromupp.(a){k} = uieditfield(tabname, 'numeric');
                app.chromupp.(a){k}.Position = [192 525 50 22];
                app.chromupp.(a){k}.ValueChangedFcn = createCallbackFcn(app, @EditAxisLims, true);
                % Create SpectraAxisLimitsEditFieldLabel
                app.ChromatogramAxisLimitsLabel.(a){k} = uilabel(tabname);
                app.ChromatogramAxisLimitsLabel.(a){k}.Position = [22 525 107 22];
                app.ChromatogramAxisLimitsLabel.(a){k}.Text = 'Spectra Axis Limits';

                % Create UITableChrom :
                % Holds saved data for integrated chromatogram peaks
                app.UITableChrom.(a){k} = uitable(tabname);
                app.UITableChrom.(a){k}.ColumnName = {'Peak Time'; 'At Wavelength'; 'Area'; 'Height'; 'Click to edit baseline'};
                app.UITableChrom.(a){k}.RowName = {}; % Remove row names
                app.UITableChrom.(a){k}.Data = {}; % Set table data to be of type cell
                app.UITableChrom.(a){k}.CellSelectionCallback = createCallbackFcn(app, @EditBaseline, true);
                app.UITableChrom.(a){k}.Position = [9 9 934 189];
                % Initialize Integration propereties as empty array to avoid error with advancedintegration app
                app.peakData.(a){k} = [];
                app.line.(a){k} = [];
                app.inttype.(a){k} = [];
                app.baselines.(a){k} = {};
    
                % Create ExportChromatogramButton :
                % Export .mat file of x and y points of chromatogram
                app.ExportChromatogramButton.(a){k} = uibutton(tabname, 'push');
                app.ExportChromatogramButton.(a){k}.Position = [22 470 220 22];
                app.ExportChromatogramButton.(a){k}.Text = 'Export Chromatogram';
                app.ExportChromatogramButton.(a){k}.ButtonPushedFcn = createCallbackFcn(app, @ExportChrom, true);
    
                % Create IntegrateButton :
                % Integration by user selecting x limits of a peak
                app.IntegrateChromatogramButton = uibutton(tabname, 'push');
                app.IntegrateChromatogramButton.Position = [22 429 220 22];
                app.IntegrateChromatogramButton.Text = 'Integrate';
                app.IntegrateChromatogramButton.ButtonPushedFcn = createCallbackFcn(app, @Integrate, true);

                % Create ExportIntegrationButton ;
                % Export saved integrations to .mat file as structure with
                % table data and baseline xy points
                app.ExportIntegrationButton = uibutton(tabname, 'push');
                app.ExportIntegrationButton.Position = [22 343 220 22];
                app.ExportIntegrationButton.Text = 'Export Integration';
                app.ExportIntegrationButton.ButtonPushedFcn = createCallbackFcn(app, @ExportIntegration, true);

                % Create AdvancedIntTool :
                % Button to open app that integrates peaks and allows user
                % control of parameters
                app.AdvancedIntTool = uibutton(tabname, 'push');
                app.AdvancedIntTool.ButtonPushedFcn = createCallbackFcn(app, @AdvancedIntegrate, true); % PeakIntegrator(app, app.TabGroup.SelectedTab.Title, find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a))));
                app.AdvancedIntTool.Position = [22 386 220 22];
                app.AdvancedIntTool.Text = 'Advanced Integration Tool';
  
                % Create IntLabel :
                % label used to display integration instructions to user
                app.Intlabel.(a){k} = uilabel(tabname);
                app.Intlabel.(a){k}.BackgroundColor = [1 1 1];
                app.Intlabel.(a){k}.WordWrap = 'on';
                app.Intlabel.(a){k}.Position = [9 260 940 29];
                app.Intlabel.(a){k}.Text = '';

                % --------------The next set of code will populate the UI elements put in place------------- %

                % Plot data 
                plot(app.UIAxesChrom.(a){k}, app.time.(a){k}, app.intensity.(a){k});
                app.chromlow.(a){k}.Value = app.UIAxesChrom.(a){k}.XLim(1);
                app.chromupp.(a){k}.Value = app.UIAxesChrom.(a){k}.XLim(2);
                plot(app.(strcat('UIAxesChrom', a)), app.time.(a){k}, app.intensity.(a){k});
                legend(app.(strcat('UIAxesChrom', a)), "String", app.tabtitles.(a))
%                 app.(strcat('UIAxesChrom', a)).Legend.String = app.tabtitles.(a);
            end
        end 
        
        function SetColor(app, ax) 
            % Sets colors for plots using distinguishable colors function above
            num = length(ax.Children); % Get number of plots on axes
            colors = distinguishable_colors(app, num, {'w','k'}); % Find colors neccesary
            ax.Children(1).Color = colors(end, :); % Update newest plot
        end

        function baseline = Int(app, low, high, num, tbl, wave, display, a, k)
            % This function is used for integration, including integrating
            % in Load parameters, and Silent Processing functionalities
            % Variables defined: 
            % low and high: X/time value for lower and upper peak limits guess
            % num: matrix of chromatogram points where first column is
                % Y/Intensity and second column is X/Time
            % tbl: Integration info table(for silent processing tbl empty)
            % wave: wavelength chrom is at (1 for chrom only)
            % display: bool of whether results should be shown(only Integrate button)
            % a and k: optional vars containing detector name and file #

            peak = height(tbl) + 1; % Get peak number using Chromtable height

            if low < num(1,2) % Check if low/high values are out of bounds
                low = num(1,2);
            end
            if high > num(end,2)
                high = num(end,2);
            end
            
            indexstart=find(num(:,2)>=low,1,'first'); %gets position of datapoint closest to lower guess 
            indexend=find(num(:,2)>=high,1,'first'); % position of mast datapoint closest to upper guess
            if length(num(indexstart:indexend,1)) < 3 % Ensure enough data is encapsulated within selected points
                app.Intdata = 0; % Incase there is already a value assigned to int data
                baseline = 0;
                return
            end
            
            % Find the peak between two boundaries
            [~,locsv]=findpeaks(-num(:,1)); % Get all the valleys in dtaset. Valleys will serve as possible bountary points for peaks
            try
                [~,locs]=findpeaks(num(indexstart:indexend,1), 'SORTSTR','descend'); % Get peaks between boundaries in descending order (locs(1) will hold the peak we want)
            catch % When less than 2 peaks are found an error occurs due to sortstr 
                [~,locs]=findpeaks(num(indexstart:indexend,1));
                locs = max(locs);
            end

            % Check that finding peaks and valleys was successful
            if length(locsv)< 1 
                app.Intdata = 0;
                baseline = 0;
                return
            end
            if length(locs)< 1
                app.Intdata = 0;
                baseline = 0;
                return
            end

            % Adjust boundary points to include all valleys and 
            vals = num(locsv,2);  % X values associated w/ valleys

            minv= min(num(1:find(num(:,2) == locs(1)+indexstart), 1)); % find min value between start and peak
            if ~ismember(minv, num(locsv,1))% add min that occurs before first peak to possible boundary points
                vals = [num(num(:,1) == minv, 2) ; vals];
            end
            maxv = min(num(find(num(:,2) == locs(1)+indexstart):1,1)); % find min between peak and end of data
            if ~ismember(maxv, num(locsv,1)) % add min that occurs after last peak to possible boudary points if not already there
                vals = [vals; num(num(:,1) == maxv, 2)];
            end
            
            % Find closet boundary point to selected point
            try % Method 1 lower bound
                lesser = vals(vals<num(locs(1)+indexstart,2)); % Get possible boundaries before peak
                [~, i] = min(abs(lesser - low)); % find index i of the closest valley to selected point before peak
                leftside = find(num(:,2) == lesser(i)); % Set the leftside boundary to be index of data to get boundary
                idxzero = find(num(1:locs(1)+indexstart,1) < 0.001, 1, "last");% Find any occurances of data crossing zero line
                if num(idxzero,2) > leftside % Check if the plot hits zero closer than peak then choosen valley
                    leftside = idxzero; % Use zero crossing point instead of choosen valley
                end
            catch % Method 2 lower bound
                leftside=locsv(find(locsv<(locs(1)+indexstart),1,'last')); %finds first valley before peak
            end
            try % Method 1 upper bound
                greater  = vals(vals>num(locs(1)+indexstart,2));
                [~, i] = min(abs(greater - high)); % find index i of the closestvalley to selected point before peak
                rightside = find(num(:,2) == greater(i));
                idxzero = find(num((locs(1)+indexstart):end,1) < 0.001, 1, "first");
                
                if num(locs(1)+indexstart+idxzero,2) < rightside
                    rightside = locs(1)+indexstart+idxzero;
                end
            catch % Method 2 upper bound
                rightside=locsv(find(locsv>(locs(1)+indexstart),1,'first')); %finds last valley after peak
            end

            % Baseline information
            base=polyfit([num(leftside,2)  num(rightside,2)],[num(leftside,1)  num(rightside,1)],1); % Linear fit between left and rightside valleys
            lines = num(leftside:rightside,2)*base(1)+base(2); % Get the y points associated with the baseline

            % Integrates area and subtracts baseline
            trapz([num(leftside,2) num(rightside,2)],[num(leftside,1) num(rightside,1)]);
            
            peakarea = trapz(num(leftside:rightside,2),num(leftside:rightside,1))-trapz([num(leftside,2) num(rightside,2) ],[num(leftside,1) num(rightside,1)]);

            % add Integration info to int data property to be used if saving peak integration
            app.Intdata = [{num(locs(1)+indexstart, 2)}, {wave}, {peakarea}, {(num(locs(1)+indexstart, 1)) - (num(locs(1)+indexstart, 2)*base(1)+base(2))}, {strcat(num2str(base(1)), '(x) +  ', num2str(base(2)))}];

            baseline{1} = num(leftside:rightside,2);
            baseline{2} = lines;

            if display == 1 % This code only occurs for integration functionalities that require populating the GUI
                if app.Label.(a){k}.Text == "Scan Data" % Scenario 1: Scan (3D) data
                    % Show integration to user and ask for user approval
                    app.hline = images.roi.Line(app.UIAxesChrom.(a){k}, "Position", ...
                        [num(leftside,2),lines(1); num(rightside,2), lines(end)], ...
                        "Deletable",false,"InteractionsAllowed","none",'Color', [0 0 0]); % ROI line shows user baseline and at the moment cannot be moved
                    app.UITableChrom.(a){k}.Data(end +1, :) = [app.Intdata]; % Display data in red row
                    addStyle(app.UITableChrom.(a){k}, uistyle("BackgroundColor", "red"), "row", peak)

                    % Determine if Integration is kept: Display question and create buttons
                    app.Intlabel.(a){k}.Text = 'Would you like to save integration?';
                    
                    % Create YesButton
                    app.YesButton = uibutton(app.(strcat('TabGroup', a)).SelectedTab, 'push');
                    app.YesButton.Position = [414 157 100 22];
                    app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @yesno, true);
                    app.YesButton.Text = 'Yes';
        
                    % Create NoButton
                    app.NoButton = uibutton(app.(strcat('TabGroup', a)).SelectedTab, 'push');
                    app.NoButton.Position = [519 157 100 22];
                    app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @yesno, true);
                    app.NoButton.Text = 'No';
        
                    % Create EditBaselineButton
                    app.EditBaselineButton = uibutton(app.(strcat('TabGroup', a)).SelectedTab, 'push');
                    app.EditBaselineButton.Position = [624 157 100 22];
                    app.EditBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @yesno, true);
                    app.EditBaselineButton.Text = 'Edit Baseline';
                    
                else % Scenario 2: Chromatogram (2D) data
                    % Show integration to user and ask for user approval
                    app.hline = images.roi.Line(app.UIAxesChrom.(a){k}, "Position", ...
                        [num(leftside,2),lines(1); num(rightside,2), lines(end)], ...
                        "Deletable",false,"InteractionsAllowed","none",'Color', [0 0 0]); % ROI line displays baseline
                    app.UITableChrom.(a){k}.Data(end +1, :) = [app.Intdata]; % Show int information in red
                    addStyle(app.UITableChrom.(a){k}, uistyle("BackgroundColor", "red"), "row", peak)

                    % Determine if Integration is kept and add buttons for user interaction
                    app.Intlabel.(a){k}.Text = 'Would you like to save integration?';
                    
                    % Create YesButton
                    app.YesButton = uibutton(app.(strcat('TabGroup', a)).SelectedTab, 'push');
                    app.YesButton.Position = [144 218 220 22];
                    app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @yesno, true);
                    app.YesButton.Text = 'Yes';
        
                    % Create NoButton
                    app.NoButton = uibutton(app.(strcat('TabGroup', a)).SelectedTab, 'push');
                    app.NoButton.Position = [369 218 220 22];
                    app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @yesno, true);
                    app.NoButton.Text = 'No';
        
                    % Create EditBaselineButton
                    app.EditBaselineButton = uibutton(app.(strcat('TabGroup', a)).SelectedTab, 'push');
                    app.EditBaselineButton.Position = [594 218 220 22];
                    app.EditBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @yesno, true);
                    app.EditBaselineButton.Text = 'Edit Baseline';
                end
            end
    
            if nargin > 7 % If a and k are specified, these variables can be saved
                app.line.(a){k}(peak,:) = base; % baseline y points
                app.peakData.(a){k}(peak,:)=[leftside,rightside]; % Index of line range
                app.inttype.(a){k}(peak) = 1; % % Save the inttype as valley to valley(1)
                app.baselines.(a){k}{peak,1} = num(leftside:rightside,2);
                app.baselines.(a){k}{peak,2} = lines;
            end
        end
        
        
        function datasetup(app, a, k)

             T = app.array.(a){k}.textdata;
             X = T(3:end, :); % Get relevant numbers
             X = str2double(X); % Change to numeric
             X = X(~all(~isfinite(X), 2), :); % Remove nan rows
             
             X = X(:, ~all(~isfinite(X), 1)); % Remove nan cols


             % Numerical Data
             M = app.array.(a){k}.data;
             % Remove any all nan rows or columns
             M = M(isfinite(M(:, end)), :);
             if height(X) < height(M)
                 X = [NaN; X];
             end
%                      M = M(:,isfinite(M(end, :)));
            if ~all(isnan(X),"all")
                M = [X,M];
            end

             app.array.(a){k}.data = M; % Combine
%                      app.array.(a){k}.data = M;
             app.intensity.(a){k} = M(2:end,2:end); % Set intensity data
             % First non nan row is wavelength
             % First non nan column is time


              % Allocate data
             if width(app.intensity.(a){k}) > 3 % scan of 3D data
                 app.time.(a){k} = M(2:end,1);
                 
%                          app.time.(a){k} = str2double(app.array.(a){k}.textdata(5:(4+height(app.intensity.(a){k})))); % This data will import time as string? Must be converted to double
                 app.wavelength.(a){k} = M(1,2:end);
             else
                 app.time.(a){k} = app.array.(a){k}.data(:,1);
                 app.intensity.(a){k} = app.array.(a){k}.data(:,2);
             end
        end
        
        function paramsetup(app, a, k, Params)

            %Adjust timeshift values accordingly
            app.TimeShiftEditField.(a){k}.Value = Params.timeshift;
            app.SelectedTimeEditField.(a){k}.Limits = [min(app.time.(a){k}) + Params.timeshift, max(app.time.(a){k})+ Params.timeshift];
            % Check that working time is within limits, if not, set to
            % closest extrema
            if Params.Time < app.SelectedTimeEditField.(a){k}.Limits(1) % below limit
                app.SelectedTimeEditField.(a){k}.Value = app.SelectedTimeEditField.(a){k}.Limits(1);
            elseif Params.Time > app.SelectedTimeEditField.(a){k}.Limits(2) % above limit
                app.SelectedTimeEditField.(a){k}.Value = app.SelectedTimeEditField.(a){k}.Limits(2);
            else % within limit and can be set accordingly
                app.SelectedTimeEditField.(a){k}.Value = Params.Time;
            end
            app.(strcat('UITable', a)).Data{k, 3} = app.SelectedTimeEditField.(a){k}.Value; % set all files table with same value of time
            
            % Check that wavelength is within limits
            if Params.Wave < app.SelectedWavelengthEditField.(a){k}.Limits(1) % below limit
                app.SelectedWavelengthEditField.(a){k}.Value = app.SelectedWavelengthEditField.(a){k}.Limits(1);
            elseif Params.Wave > app.SelectedWavelengthEditField.(a){k}.Limits(2) % above limit
                app.SelectedWavelengthEditField.(a){k}.Value = app.SelectedWavelengthEditField.(a){k}.Limits(2);
            else % within limit and can be set accordingly
                app.SelectedWavelengthEditField.(a){k}.Value = Params.Wave;
            end
            app.(strcat('UITable', a)).Data{k, 4} = app.SelectedWavelengthEditField.(a){k}.Value;

            % Chrom set up
            [~,idxw] = min(abs(app.SelectedWavelengthEditField.(a){k}.Value-app.wavelength.(a){k})); % find index of wavelength
            cla(app.UIAxesChrom.(a){k}) % clear axes
            app.SaveChrom.(a){k}.Data = []; % remove any current data from save chrom table
            if isfield(Params,'chromwaves') % Checking that chromwaves is a field of params, checks that there are saved chromatograms
                for n = 1:length(Params.chromwaves) 
                    % if the wavelength is not in limits
                    if (Params.chromwaves(n) < app.SelectedWavelengthEditField.(a){k}.Limits(1)) | (Params.chromwaves > app.SelectedWavelengthEditField.(a){k}.Limits(2))
                        continue
                    end
                    app.SaveChrom.(a){k}.Data(end+1,1) = [Params.chromwaves(n)]'; % set table data
                    [~,idx] = min(abs(app.wavelength.(a){k} - Params.chromwaves(n))); % get index of closest wavelength and plot
                    plot(app.UIAxesChrom.(a){k},app.time.(a){k}+Params.timeshift, app.intensity.(a){k}(:,idx), 'DisplayName', string(Params.chromwaves(n)));
                    hold(app.UIAxesChrom.(a){k}, "on")
                    SetColor(app, app.UIAxesChrom.(a){k})
                end
            end

            % Plot working plot on allfiles tab, selected filese tab,
            % and adjust axes limits as given in parameters
            plot(app.UIAxesChrom.(a){k},app.time.(a){k}+Params.timeshift, app.intensity.(a){k}(:,idxw), 'DisplayName', string(app.SelectedWavelengthEditField.(a){k}.Value));
            SetColor(app, app.UIAxesChrom.(a){k})
            app.(strcat('UIAxesChrom', a)).Children(end-k+1).YData = app.intensity.(a){k}(:, idxw);
            SetColor(app, app.(strcat('UIAxesChrom', a)))
            app.(strcat('UIAxesChrom', a)).Children(end+1-k).XData = app.time.(a){k} + Params.timeshift;
            app.chromlow.(a){k}.Value = Params.lowchrom;
            app.chromupp.(a){k}.Value = Params.uppchrom;
            app.UIAxesChrom.(a){k}.XLim = [Params.lowchrom, Params.uppchrom];

            %Spectra set up
            [~,idxt] = min(abs(app.SelectedTimeEditField.(a){k}.Value-app.time.(a){k}-Params.timeshift));
            cla(app.UIAxesSpec.(a){k})
            app.SaveSpec.(a){k}.Data = [];
            
            if isfield(Params, 'spectimes')
                for n = 1:length(Params.spectimes)
                    if (Params.spectimes(n) < app.SelectedTimeEditField.(a){k}.Limits(1)) | (Params.spectimes > app.SelectedTimeEditField.(a){k}.Limits(2))
                        continue
                    end
                    app.SaveSpec.(a){k}.Data(end+1,1) = [Params.spectimes(n)]';
                    [~,idx] = min(abs(app.time.(a){k}+Params.timeshift - Params.spectimes(n)));
                    plot(app.UIAxesSpec.(a){k},app.wavelength.(a){k}, app.intensity.(a){k}(idx,:), 'DisplayName', string(Params.spectimes(n)));
                    hold(app.UIAxesSpec.(a){k}, "on")
                    SetColor(app, app.UIAxesChrom.(a){k})
                end
            end

            plot(app.UIAxesSpec.(a){k},app.wavelength.(a){k}, app.intensity.(a){k}(idxt,:), 'DisplayName', string(app.SelectedTimeEditField.(a){k}.Value));
            SetColor(app, app.UIAxesSpec.(a){k})
            app.(strcat('UIAxesSpec', a)).Children(end-k+1).YData = app.intensity.(a){k}(idxt, :);
            SetColor(app, app.(strcat('UIAxesSpec', a)))
            app.(strcat('UIAxesSpec', a)).Children(end+1-k).XData = app.wavelength.(a){k};
            app.speclow.(a){k}.Value = Params.speclow;
            app.specupp.(a){k}.Value = Params.specupp;
            app.UIAxesSpec.(a){k}.XLim = [Params.speclow, Params.specupp];
            
            % 3D set up
            if app.UIAxes3D.(a){k}.Visible == "on"
                % 3D plt
                app.UIAxes3D.(a){k}.Children.YData = app.time.(a){k} + Params.timeshift;
                % Select cuts plt
                app.UIAxes.(a){k}.Children(end).YData = app.time.(a){k} + Params.timeshift;
    
                app.timeROILine.(a){k}.Position = [app.UIAxes.(a){k}.XLim(1)-1, app.SelectedTimeEditField.(a){k}.Value; app.UIAxes.(a){k}.XLim(2)*1.01, app.SelectedTimeEditField.(a){k}.Value];
                app.waveROILine.(a){k}.Position = [app.SelectedWavelengthEditField.(a){k}.Value, app.UIAxes.(a){k}.YLim(1)-1;app.SelectedWavelengthEditField.(a){k}.Value,app.UIAxes.(a){k}.YLim(2)*1.01];
            end

            % Integration set up 
            app.UITableChrom.(a){k}.Data = {}; % Remove any current integrations
            if isfield(Params, 'Intwl') % check that there are integrations to try
                for n = 1:length(Params.Intwl)
                    wave = Params.Intwl(n);
                    if (wave < app.SelectedWavelengthEditField.(a){k}.Limits(1)) | (wave > app.SelectedWavelengthEditField.(a){k}.Limits(2))
                        continue % check if chromatogram is in range
                    end 

                    [~, idxwave] = min(abs(app.wavelength.(a){k}-wave)); % get index of chromatogram wavelength
                    num = [app.intensity.(a){k}(:, idxwave), app.time.(a){k}]; % set matrix of y and x value vectors
                    Int(app, Params.IntstartRange(n), Params.IntendRange(n), num, app.UITableChrom.(a){k}.Data , wave, 0, a, k); % Integrate using function with the left and right boundary times as those given from parameters
                    if ~iscell(app.Intdata) % If the intdata parameter was set to zero, it wont be a cell and that means finding peak waws unsuccessful
                        uialert(app.HPLCApp, strcat('Peak ', str2double(n), ' not found', 'Error'))
                        continue
                    end
                    app.UITableChrom.(a){k}.Data(end+1, :) = [app.Intdata]; % add data
                end
            end
        end
    end
    
    methods (Access = public)
        
        function aligningpeaks(app, shft, a, k)
            % Align peaks function runs from Align peaks app to pass data
            % back to mainapp and adjust data accordingly

            % shft is how much is shifted from previous config (relative shift)
            % new val is how much is shifted from origional data
            newval = app.TimeShiftEditField.(a){k}.Value + shft;

            % Adjust app data accordingly
            app.TimeShiftEditField.(a){k}.Value = newval; % Set the numeric field to hold new shift value
            if app.Label.(a){k}.Text == "Scan Data" % When data includes spectras, time values must be changed
                app.SelectedTimeEditField.(a){k}.Limits = [min(app.time.(a){k}) + newval, max(app.time.(a){k})+newval]; % readjust time limits
                app.SelectedTimeEditField.(a){k}.Value = app.SelectedTimeEditField.(a){k}.Value + shft; % Readjust current working time
                app.(strcat('UITable', a)).Data{k, 3} = app.SelectedTimeEditField.(a){k}.Value; % Add new working time value to uitable on all files tab
            end
            
            % Chrom
            for n = 1:length(app.UIAxesChrom.(a){k}.Children) % For each chrom plot, shift xdata
                app.UIAxesChrom.(a){k}.Children(n).XData = app.UIAxesChrom.(a){k}.Children(n).XData + shft;
            end
            app.(strcat('UIAxesChrom', a)).Children(end+1-k).XData = app.(strcat('UIAxesChrom', a)).Children(end+1-k).XData + shft; % Shift all files chromatogram as well

            % Int data
            for n = 1:height(app.UITableChrom.(a){k}.Data) % For each saved peak integration, shift timepoint
                app.UITableChrom.(a){k}.Data{n,1} = app.UITableChrom.(a){k}.Data{n,1} + shft;
            end

            % 3D Data
            if app.UIAxes3D.(a){k}.Visible == "on" % Only occurs for scan data
                % Adjust 3D plt
                app.UIAxes3D.(a){k}.Children.YData = app.time.(a){k} + newval;
                % Adjust select cuts plt and ROIlines
                app.UIAxes.(a){k}.Children(end).YData = app.time.(a){k} + newval;
                app.timeROILine.(a){k}.Position(3) = app.timeROILine.(a){k}.Position(3) + shft;
                app.timeROILine.(a){k}.Position(4) = app.timeROILine.(a){k}.Position(4) + shft;
                app.waveROILine.(a){k}.Position(3) = app.waveROILine.(a){k}.Position(3) + shft;
                app.waveROILine.(a){k}.Position(4) = app.waveROILine.(a){k}.Position(4) + shft;

                % Spec Legends
                for n = 1:length(app.UIAxesSpec.(a){k}.Children)
                    app.UIAxesSpec.(a){k}.Children(n).DisplayName = num2str(str2double(app.UIAxesSpec.(a){k}.Children(n).DisplayName) + shft);
                end
            end
        end
        
        function SaveIntStuff(app, a, k, Intdata, peakData, line, type, bl)
            for n = 1:height(Intdata)
                % Update table on main app
                app.UITableChrom.(a){k}.Data(end+1,:) = [Intdata(n,:)];
                % Update integration related variables 
                app.peakData.(a){k}(end+1,:) = peakData(n,:);
                app.line.(a){k}(end+1,:) = line(n,:);
                app.inttype.(a){k}(end+1) = type(n);
                app.baselines.(a){k}{end+1, 1} = bl{n, 1};
                app.baselines.(a){k}{end, 2} = bl{n, 2};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0, 'DefaultLineLineWidth', 2); % Thicker line width

            % Initialize the number of file variables all to zero
            app.numfile.PDA = 0;
            app.numfile.PL = 0;
            app.numfile.Conductivity = 0;
            app.numfile.RefractiveIndex = 0;
            app.numfile.Electrochemical = 0;

            app.UITablePDA.ColumnEditable = [true false true true];
            app.UITablePL.ColumnEditable = [true false true true];
            app.UITableConductivity.ColumnEditable = [true false true true];
            app.UITableRefractiveIndex.ColumnEditable = [true false true true];
            app.UITableElectrochemical.ColumnEditable = [true false true true];
        end

        % Button pushed function: LoadFilesButtonConductivity, 
        % ...and 4 other components
        function Loadfiles(app, event)
            a = app.TabGroup.SelectedTab.Title; % Detector name
            tbl = strcat('UITable', a); % Get allfiles table name of current detector tab
            
            % Get file(s)
            [app.filename.(a),app.pathname] = uigetfile({'*.csv'; '*.xlsx'}, 'Select HPLC data file to import.', 'MultiSelect','on');

            if ischar(app.filename.(a)) == 1
                  app.filename.(a) = cellstr(app.filename.(a));
            end

            if isequal(app.filename.(a), 0) % The filename equals zero when cancel is pressed
               return;
            end

            app.numfile.(a) = app.numfile.(a) + length(app.filename.(a)); % Increase length of num of files
            start = height(app.(tbl).DisplayData) + 1; % Starting index of adding files

            for k = start:app.numfile.(a) % Create titles of tabs
                app.tabtitles.(a){k} = char("File" + k);
            end
            
            if start == 1 % When this is first set of data for the detector
                % Data in table contains file number, filename, timepoint, wavelength
                app.(tbl).Data = [app.tabtitles.(a)', (app.filename.(a))', cell(1,app.numfile.(a))', cell(1,app.numfile.(a))'];
                app.(tbl).ColumnWidth = {'fit', 'auto', 'auto', 'auto'};
            else % Adding data to table 
                 app.(tbl).Data(start:app.numfile.(a), 1:2) = [(app.tabtitles.(a)(start:app.numfile.(a)))', ((app.filename.(a)))'];
            end

            hold(app.(strcat('UIAxesSpec', a)), "on") % keep hold on so plots are kept as new ones are added
            hold(app.(strcat('UIAxesChrom', a)), "on")

            for k=start:app.numfile.(a) % Set up of each added file
                 % Importing data
%                  try
                     app.array.(a){k} = importdata(append(app.pathname, app.filename.(a){k-start+1})); % Entire files info

                     datasetup(app, a, k)
%                  catch 
%                      uialert(app.HPLCApp, 'A file was unable to be opened', 'error')
%                      continue
%                  end

                 % Create Tabs
                 app.tabs.(a){k} = uitab(app.(strcat('TabGroup', a)), "Title", "File" + k);
                 tabsetup(app, k, a) % Funtion will add uielements for each tab
            end

            hold(app.(strcat('UIAxesSpec', a)), "off") % Hold no longer required
            hold(app.(strcat('UIAxesChrom', a)), "off")
        end

        % Callback function
        function TimeROIChange(app, event)
            % Callback associated with time numeric field change
            % When working time is changed, the spectra's and roiline changes
            
            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a))); % File number

            shft = app.TimeShiftEditField.(a){k}.Value; % Time shift from origional time axis
            
            if app.keepspec.(a){k} == 0 % Keep spec is false except when current working plot is saved
                delete(app.UIAxesSpec.(a){k}.Children(1)) % Plot is not saved, so working plot is deleted to be replaced by new spectra
            else % Working plot is saved, bool keepspec is true, working plot added on top of current plots
                app.keepspec.(a){k} = 0; % Revert bool back to false
            end 
            % Find closest value
            [~, idx] = (min(abs(app.SelectedTimeEditField.(a){k}.Value-app.time.(a){k}-shft))); % Get Index of closest time
            value = app.time.(a){k}(idx);
            app.SelectedTimeEditField.(a){k}.Value = value;
            app.(strcat('UITable', a)).Data{k,3} = value; % Allfiles table time change
            app.timeROILine.(a){k}.Position(3) = value; % Chang ROILine position
            app.timeROILine.(a){k}.Position(4) = value;
            plot(app.UIAxesSpec.(a){k}, app.wavelength.(a){k}, app.intensity.(a){k}(idx,:), 'DisplayName',string(app.SelectedTimeEditField.(a){k}.Value));
            SetColor(app, app.UIAxesSpec.(a){k})
            app.(strcat('UIAxesSpec', a)).Children(end-k+1).YData = app.intensity.(a){k}(idx,:);
            SetColor(app, app.(strcat('UIAxesSpec', a)))
        end

        % Callback function
        function WavelengthROIChange(app, event)
            % Callback associated with wavelength numeric field change
            % When working wavelength is changed, the chromatograms's and roiline changes

            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a))); % File number
            shft = app.TimeShiftEditField.(a){k}.Value; % timeshift from origional time axis

            if app.keepchrom.(a){k} == 0 % Bool to determine if chrom was saved
                delete(app.UIAxesChrom.(a){k}.Children(1)) % If ont saved delete working plot to be replaced
            else % If saved, leave plot and revert bool back to 0
                app.keepchrom.(a){k} = 0;
            end  
            % Find closest value
            [~,idx] = min(abs(app.SelectedWavelengthEditField.(a){k}.Value-app.wavelength.(a){k})); % Index of closest point in data
            value = app.wavelength.(a){k}(idx);
            app.SelectedWavelengthEditField.(a){k}.Value = value;
            app.(strcat('UITable', a)).Data{k,4} = value; % Allfiles table
            app.waveROILine.(a){k}.Position(1) = value; % RoiLine
            app.waveROILine.(a){k}.Position(2) = value;
            plot(app.UIAxesChrom.(a){k},app.time.(a){k}+shft, app.intensity.(a){k}(:,idx), 'DisplayName', string(app.SelectedWavelengthEditField.(a){k}.Value));
            SetColor(app, app.UIAxesChrom.(a){k})
            app.(strcat('UIAxesChrom', a)).Children(end-k+1).YData = app.intensity.(a){k}(:, idx);
            SetColor(app, app.(strcat('UIAxesChrom', a)))
        end

        % Callback function
        function timeshiftzero(app, event)
            % Time shift zero will revert any time shifts back to origional
            % unshifted time axis

            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a))); % File number
            shft = - (app.TimeShiftEditField.(a){k}.Value); % Negative shift of current timeshift value

            app.TimeShiftEditField.(a){k}.Value = 0; % Reset edit fields and their limits
            app.SelectedTimeEditField.(a){k}.Limits = [min(app.time.(a){k}), max(app.time.(a){k})];
            app.SelectedTimeEditField.(a){k}.Value = app.SelectedTimeEditField.(a){k}.Value + shft;
            % 3D plt
            app.UIAxes3D.(a){k}.Children.YData = app.time.(a){k};
            % Select cuts plt
            app.UIAxes.(a){k}.Children(end).YData = app.time.(a){k};
            % Update Roiline position
            app.timeROILine.(a){k}.Position(3) = app.timeROILine.(a){k}.Position(3) + shft;
            app.timeROILine.(a){k}.Position(4) = app.timeROILine.(a){k}.Position(4) + shft;
            app.waveROILine.(a){k}.Position(3) = app.waveROILine.(a){k}.Position(3) + shft;
            app.waveROILine.(a){k}.Position(4) = app.waveROILine.(a){k}.Position(4) + shft;

            %Integration
            for n = 1:height(app.UITableChrom.(a){k}.Data)
                app.UITableChrom.(a){k}.Data{n,1} = app.UITableChrom.(a){k}.Data{n,1} + shft;
            end

            % Chrom
            for n = 1:length(app.UIAxesChrom.(a){k}.Children)
                app.UIAxesChrom.(a){k}.Children(n).XData = app.UIAxesChrom.(a){k}.Children(n).XData + shft;
            end
            app.(strcat('UIAxesChrom', a)).Children(end+1-k).XData = app.(strcat('UIAxesChrom', a)).Children(end+1-k).XData + shft;
        end

        % Callback function
        function SaveSpectra(app, event)
            % Save a plot to compare other Spectra cuts on the same axis.
            % Data is saved to a table, SaveSpec. These saved plots can be
            % deleted by pressing delete while highlighting a row and
            % callback deleteplot will execute
            
            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)); % File number

            if ismember(app.SelectedTimeEditField.(a){k}.Value, app.SaveSpec.(a){k}.DisplayData) % Prevent plot from saving multiple times to prevent error
                uialert(app.HPLCApp, 'Plot already saved', 'Error')
                return
            end
            app.SaveSpec.(a){k}.Data(end+1, :) = app.SelectedTimeEditField.(a){k}.Value; % Add to table
            app.keepspec.(a){k} = 1; % Bool becomes true, so when working plot is changed, it is known not to delete the current plot
        end

        % Callback function
        function SaveChromatogram(app, event)
            % Save a plot to compare other Spectra cuts on the same axis.
            % Data is saved to a table, SaveSpec. These saved plots can be
            % deleted by pressing delete while highlighting a row and
            % callback deleteplot will execute
            
            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)); % File number

            if ismember(app.SelectedWavelengthEditField.(a){k}.Value, app.SaveChrom.(a){k}.DisplayData) % Prevent plot from saving multiple times to prevent error
                uialert(app.HPLCApp, 'Plot already saved', 'Error')
                return
            end
            app.SaveChrom.(a){k}.Data(end+1, :) = app.SelectedWavelengthEditField.(a){k}.Value; % Add to table
            app.keepchrom.(a){k} = 1; % Bool becomes true, so when working plot is changed, it is known not to delete the current plot
        end

        % Callback function
        function getselectedcell(app, event)
             % To get selected cell of savespec and savechrom tables for deleteplot callback
            app.selectedcell = event.Indices(1);
        end

        % Callback function
        function deleteplot(app, event)
            % ButtonDownFcn to delete a saved plot from either chrom or spectra
            
            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)); % File number

            key = event.Key; % Get which key is used as button down
            idx = app.selectedcell; % Get selected cell from getselectedcell callback

            if key == "delete" % If the key delete is used...
                if event.Source.ColumnName == "Time" % ...on time/savespec table...
                    delete(app.UIAxesSpec.(a){k}.Children(end - idx + 1)) % ...delete spectra
                elseif event.Source.ColumnName == "Wavelength" % ...on wawvelength/savechrom table...
                    delete(app.UIAxesChrom.(a){k}.Children(end - idx + 1)) % ...delete chromatogram
                end
                event.Source.Data(idx) = []; % Remove data from selected table row
            end
        end

        % Button pushed function: ExportSpectraButtonConductivity, 
        % ...and 2 other components
        function ExportSpec(app, event)
            a = app.TabGroup.SelectedTab.Title; % Get the detector for the Spectra's

            % Two circumstances for saving Spectras            
            if app.(strcat('TabGroup', a)).SelectedTab.Title == "All Files" % Circumstance one is saving All-files Spectras
                tbl = app.(strcat('UITable', a)); % Name of table on all files tab. This is distinguished by detector (a)
                spec = app.(strcat('UIAxesSpec', a)); % Get the axes component sontaining the spectra. This is where the data for the spectras will come from.
                ExportSpec = table(); % Initialize a table that will contain Spectras
                ExportSpec.Wavelength = (spec.Children(end).XData)'; % Set first column the wavelength of first file's Spectra
                colname = strcat(tbl.Data{1,2}, ' (', num2str(tbl.Data{1,3}), ')'); % Create a column name to say filename(time)
                ExportSpec.(colname) = (spec.Children(end).YData)'; % Set second column as first files intensities

                for k = 2:(length(spec.Children)) % For loop for each of the following files Spectra data
                    tabletemp = table(); % Create a temporary table to hold data of next file
                    tabletemp.Wavelength = (spec.Children(length(spec.Children) +1 - k).XData)'; % Wavelength as first column
                    colname = strcat(tbl.Data{k,2}, ' (', num2str(tbl.Data{k,3}), ')'); % column name: filename(time)
                    tabletemp.(colname) = (spec.Children(length(spec.Children) +1 - k).YData)'; % Intensity as second column
                    ExportSpec = outerjoin(ExportSpec, tabletemp, MergeKeys=true); % Joining the tables will combine the time column even when different files have different time vectors
                end

                % Exporting...
                uisave('ExportSpec', strcat(a,'SpectraData')); % Save .mat file containing Exported Spectra structure
            else % Circumstance two is saving a files saved Spectras
                k = strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)); % Get the file the user is exporting Spectra from
                if length(app.SaveSpec.(a){k}.DisplayData) < 1 % If there are no files saved, stop the callback 
                    uialert(app.HPLCApp, 'Please Save Spectra to export', 'Error')
                    return
                end

                ExportSpec = table(); % Initialize a table that will contain Spectras
                ExportSpec.Wavelength = (app.UIAxesSpec.(a){k}.Children(end).XData)'; % Set first column as the wavelength of first spectra

                for n = 1:(length(app.SaveSpec.(a){k}.DisplayData)) % For loop for each of the following Spectra's data
                    colname = strcat('At Time: ', num2str(app.SaveSpec.(a){k}.DisplayData(n,1)));% Column name: 'At Time: time'
                    ExportSpec.(colname) = (app.UIAxesSpec.(a){k}.Children(end - n + 1).YData)'; % Intensity as given column
                end

                % Exporting...
                uisave('ExportSpec', strcat(app.filename.(a){k}(1:end-4),'SpecData')); % Save .mat file containing Export Spectra structure
            end
        end

        % Button pushed function: ExportChromButtonConductivity, 
        % ...and 2 other components
        function ExportChrom(app, event)
            % Chromatogram X and Y points can be exported from all files tab or individual file tabs

            a = app.TabGroup.SelectedTab.Title; % Get the detector for the Chromatogram's 
            k = strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)); % Get the file the user is exporting Chromatograms from
            
            % Three circumstances for saving Chromatograms 
            if app.(strcat('TabGroup', a)).SelectedTab.Title == "All Files" % Circumstance one is saving All-files Chrom
                tbl = app.(strcat('UITable', a)); % Allfiles table name
                chrom = app.(strcat('UIAxesChrom', a)); % Allfiles chromatogram axis
                ExportChrom = table(); % Create a table for exported data
                % Add first files data
                ExportChrom.Time = (chrom.Children(end).XData)';% Add time data to the first 
                colname = strcat(tbl.Data{1,2}, ' (', num2str(tbl.Data{1,4}), ')'); % Write column name to say filename(wavelength)
                ExportChrom.(colname) = (chrom.Children(end).YData)'; % add intensity data corresponding to first files time data

                for k = 2:(length(chrom.Children)) % Adding data for remaining files info
                    tabletemp = table(); % Temporary table will be used so x data from each file can be merged to exportchrom table since different files have different x vectors
                    tabletemp.Time = (chrom.Children(length(chrom.Children) +1 - k).XData)'; % Get time data of file
                    colname = strcat(tbl.Data{k,2}, ' (', num2str(tbl.Data{k,4}), ')'); % create name of new file y data as filename(wavelength)
                    tabletemp.(colname) = (chrom.Children(length(chrom.Children) +1 - k).YData)'; % Corresponding y file data
                    ExportChrom = outerjoin(ExportChrom, tabletemp, MergeKeys=true); % Join temporary table data to ExportChrom data...this will merge Xdata/time
                end
    
                % Exporting...
                uisave('ExportChrom', strcat(a,'ChromData')); % Save .mat file containing ExportData structure
            elseif app.Label.(a){k}.Text == "Scan Data"  % Circumstance two is saving a files saved Chroms when the data is scanned data
                if length(app.SaveChrom.(a){k}.DisplayData) < 1 % Must have saved chromatograms in order to export 
                    uialert(app.HPLCApp, 'Please Save Chromatograms to export', 'Error')
                    return
                end

                ExportChrom = table(); % Create a table for exporting data
                ExportChrom.Time = (app.UIAxesChrom.(a){k}.Children(end).XData)'; % Create time column, this should not change for same files data

                for n = 1:(length(app.SaveChrom.(a){k}.DisplayData)) % for each of the remaining saved chroms
                    colname = strcat('At Wavelength: ', num2str(app.SaveChrom.(a){k}.DisplayData(n,1))); % Create column name for the current saved chrom to say 'At wavelength: wavelength'
                    ExportChrom.(colname) = (app.UIAxesChrom.(a){k}.Children(end - n + 1).YData)'; % Add current plot saved chrom data
                end

                % Exporting...
                uisave('ExportChrom', strcat(app.filename.(a){k}(1:end-4),'ChromData')); % Save .mat file containing ExportData structure
            else % Circumstance three is saving the chrom of a chromatogram only file
                ExportChrom = table(); % Create a table
                ExportChrom.Time = (app.UIAxesChrom.(a){k}.Children(end).XData)'; % Set time and intensity for only chromatogram option
                ExportChrom.Intensity = (app.UIAxesChrom.(a){k}.Children(end).YData)';

                % Exporting...
                uisave('ExportChrom', strcat(app.filename.(a){k}(1:end-4),'ChromData')); % Save .mat file containing ExportData structure
            end
        end

        % Callback function
        function ExportIntegration(app, event)
            % Exported integration data is organized as tables in a
            % structure called Integration
            % The Integration structure is organized first into each peak
            % (labeled peak 1 ... peak n) and then further split into a
            % summary  and baseline points. The summary of the peak has
            % all information from the ui table.        

            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a))); % Selected file number

            if ~isstruct(app.peakData) % Check there are interations saved to table. none are saved when peakData propertyu is not type struct
                uialert(app.HPLCApp, 'No Integrations saved', 'Error')
                return
            end
            
            for n = 1:height(cell2mat(app.UITableChrom.(a){k}.Data(:,2)))
                % Create table using data gathered from UITableChrom for each row
                Peaktime = app.UITableChrom.(a){k}.Data{n,1};
                At_Wavelength =  app.UITableChrom.(a){k}.Data{n,2};
                Area = app.UITableChrom.(a){k}.Data{n,3};
                Height = app.UITableChrom.(a){k}.Data{n,4};
                Baseline = app.UITableChrom.(a){k}.Data(n,5); 
                % Add data to Integration.("Peak n").Summary
                Integration.(strcat('Peak ', num2str(n))).Summary = table(Peaktime, At_Wavelength, Area, Height, Baseline);
                
%                 % Find XY points for baseline
%                 X = [app.time.(a){k}(app.peakData.(a){k}(n,1):app.peakData.(a){k}(n,2))]; % X consists of time values between start and end peak index held in peakData property
%                 X = reshape(X, [length(X), 1]);
%                 Y = (app.line.(a){k}(n,1)*X)+ app.line.(a){k}(n,2); % Y is determined by linear equation using constants saved in line property

                % Add data to Integration.("Peak n").Baseline_XY_Points
                Integration.(strcat('Peak ', num2str(n))).Baseline_XY_Points = table(reshape(app.baselines.(a){k}{n,1}, [length(app.baselines.(a){k}{n,1}), 1]), reshape(app.baselines.(a){k}{n,2}, [length(app.baselines.(a){k}{n,2}), 1]), 'VariableNames', {'X', 'Y'});
%                 table(X, Y);
            end

            % Exporting...
            uisave('Integration', strcat("Integration - ", app.(strcat('TabGroup', a)).SelectedTab.Title)); % Save .mat file containing FileData structure
        end

        % Callback function
        function Integrate(app, event)
            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)); % File number

            for n = 2: length(app.UIAxesChrom.(a){k}.Children) % Turn off visibility of all chromatograms except working plot
                app.UIAxesChrom.(a){k}.Children(n).Visible = 'off';
            end
            
            tbl = app.UITableChrom.(a){k}.DisplayData; % Gather the integration table
            peak = height(app.UITableChrom.(a){k}.DisplayData) + 1; % Get peak number: the peak number is height of current integration table plus 1
            num = [app.UIAxesChrom.(a){k}.Children(1).YData', app.UIAxesChrom.(a){k}.Children(1).XData']; % Gather data for integration in matrix [ydatavect, xdatavect]
            if app.Label.(a){k}.Text == "Scan Data" % Scan data type will also need wavelength
                wave = app.SelectedWavelengthEditField.(a){k}.Value;
            end

            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')
        
            % Get user selected peak boundry points on plot through roipoints
            % Set up figure handle visibility, run ginput, and return state
            app.HPLCApp.HandleVisibility = 'callback';    % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.HPLCApp)           % Make fig current

            app.Intlabel.(a){k}.Visible = 'on';
            app.Intlabel.(a){k}.Text = 'Select the bounds of a peak to integrate';
            
            disableDefaultInteractivity(app.UIAxesChrom.(a){k}) %disable axis movement for roi lines
            for n = 1:2 % Draw 2 roi points and save boundaries to peakboundaries
                peakboundaries(peak,n) = drawpoint(app.UIAxesChrom.(a){k});
            end

            low = min(peakboundaries(peak,1).Position(1), peakboundaries(peak,2).Position(1)); % get lower boundary x position
            high = max(peakboundaries(peak,1).Position(1), peakboundaries(peak,2).Position(1)); % get upper boundary x position
            
            delete(peakboundaries(peak, :)) % delete unneeded peakboundariese variable
            if app.Label.(a){k}.Text == "Scan Data" % Integration inputs depend on if the data is scan/3d or chromatogramonly/2d
                Int(app, low, high, num, tbl, wave, 1, a, k); % Includes a and k for saving variables, and bool 1 to show integration on the plot
            else
                Int(app, low, high, num, tbl, 1, 1, a, k) % can set wavelength to 1
            end 
%             app.Intlabel.(a){k}.Text = '';
        end

        % Callback function
        function AdvancedIntegrate(app, event)
            % Gather parameters 
            a = app.TabGroup.SelectedTab.Title; % a is to get detector of tab params
            k = find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)));% k is to get file of tab params

            if app.Label.(a){k}.Text == "Scan Data" % Get the wavelength values
                wave = app.SelectedWavelengthEditField.(a){k}.Value;
            else
                wave = 1;
            end
            col = distinguishable_colors(app, 4, {'w', 'k'}); % For if you want colors in peak integrator to be using distinguishable colors
            % Run peak integrator app which runs in a different window
            PeakIntegrator(app, app.TabGroup.SelectedTab.Title, find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a))), wave, col);

        end

        % Callback function
        function yesno(app, event)
            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)); % File number

            % Determine what button pushed
            if event.Source.Text == "Yes" % Yes: keep current integration
                delete(app.YesButton) % Delete buttons
                delete(app.NoButton)
                delete(app.EditBaselineButton)
                removeStyle(app.UITableChrom.(a){k}) % Temporary table data becomes permenant, so it doen't need to be red anymore
            elseif event.Source.Text == "Edit Baseline" % Edit Baseline: keep integration, but adjust baseline
                delete(app.YesButton) % Delete buttons
                delete(app.NoButton)
                delete(app.EditBaselineButton)
                removeStyle(app.UITableChrom.(a){k}) % No longer need red line in table
                app.UITableChrom.(a){k}.Data(end,:) = []; % Remove current data from table

                % Allow ROI baseline to be moved to change integration
                app.UIAxesChrom.(a){k}.Children(1).InteractionsAllowed = "reshape";
                app.Intlabel.(a){k}.Text = 'Drag baseline to adjust integration'; % Change instructions

                % Create OkayButton
                OkayButton = uibutton(app.(strcat('TabGroup', a)).SelectedTab, 'push');
                if app.Label.(a){k}.Text ==  "Scan Data"
                    OkayButton.Position = [414 157 100 22];
                else
                    OkayButton.Position = [144 218 220 22];
                end
                OkayButton.ButtonPushedFcn = createCallbackFcn(app, @yesno, true);
                OkayButton.Text = 'Okay';
                waitfor(OkayButton)
            elseif event.Source.Text == "Okay" % Okay: Edit baseline user confirmation for completing moving baseline
                delete(event.Source) % Delete button
                % Get data from plot: children(1) is roi line, children(2) is chromatogram of interest
                num = [app.UIAxesChrom.(a){k}.Children(2).YData', app.UIAxesChrom.(a){k}.Children(2).XData'];
                % Find new left and right index
                leftside = find(num(:,2) > app.hline.Position(1,1),1,'first'); % Find closest x points to baseline endpoint positions
                rightside = find(num(:, 2) > app.hline.Position(2,1),1,'first');
                % Draw new line
                base=polyfit([num(leftside,2)  num(rightside,2)],[num(leftside,1)  num(rightside,1)],1); %fit linear between rigth and left side valleys
                app.line.(a){k}(height(app.UITableChrom.(a){k}.DisplayData) + 1,:) = base; % Readjust line property to new line coefficients
                % adjust data in table
                app.Intdata(3:5)=[
                    {trapz(num(leftside:rightside,2),num(leftside:rightside,1))-trapz([num(leftside,2) num(rightside,2)], [num(leftside,1) num(rightside,1)])}, ...
                    {(num(num(:,2) == app.Intdata{1}, 1)) - (app.Intdata{1}*base(1)+base(2))}, ...
                    {strcat(num2str(base(1)), '(x) +  ', num2str(base(2)))}];
                app.UITableChrom.(a){k}.Data(end +1, :) = app.Intdata;
            else % No: Dont save integration
                delete(app.YesButton) % Delete buttons
                delete(app.NoButton)
                delete(app.EditBaselineButton)
                removeStyle(app.UITableChrom.(a){k}) % remove red uitable style
                app.UITableChrom.(a){k}.Data(end,:) = []; % Remove temporary int data
            end
            
            for n = 2: length(app.UIAxesChrom.(a){k}.Children) % Turn on visibility of all plots
                app.UIAxesChrom.(a){k}.Children(n).Visible = 'on';
            end

            delete(app.hline) % Delete ROI Line
            app.Intlabel.(a){k}.Visible = 'off'; % Turn off instructive label
            enableDefaultInteractivity(app.UIAxesChrom.(a){k}) % Allow user to interact with uiaxes again
            uiresume(app.HPLCApp) % Resume integration code
        end

        % Callback function
        function EditBaseline(app, event)
            % A table selection callback for the integration
            % tables(UITableChrom) that will ony excecute for selections of
            % the 5th column which serves as a button for users to manully
            % adjust peak integration baselines
            
            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)); % file number
            indices = event.Indices; % Indices of table 
            shft = app.TimeShiftEditField.(a){k}.Value; % Recieve the timeshift value (0 is standard)

            if indices(2)==5 % we only care about that 5th column of the table
                if app.inttype.(a){k}(indices(1)) ~= 1 
                    % Since edit baseline only works as a point to point
                    % linear baseline integration, warn the user if they
                    % are changing the baseline on a dropdown or gauss
                    % integrated peak
                    selection = uiconfirm(app.HPLCApp, "Integration meathod will be converted to valley-to-valley, Would you like to proceed?","Confirm");
                    switch selection
                        case 'Cancel' % If cancel is pressed by user, editbaseline action will be canceled
                            return
                    end
                end
                base = app.line.(a){k}(indices(1),:); % line coefficiets
                range = app.peakData.(a){k}(indices(1), :);  % 1x2 start and end indices of peak
                if app.Label.(a){k}.Text == "Scan Data" % For 3D scan data...
                    [~, idxwave] = min(abs(app.wavelength.(a){k}-app.UITableChrom.(a){k}.Data{indices(1), 2})); % get index of closest wavelength
                    % To edit integration, the plot at given wavelenght
                    % must become working plot.
                    if app.keepchrom.(a){k} == 0 % Link in any other time the working plot is changed. delete the most recent plot to be replaced unless keepchrom is 1 and dthat plot has been saved
                         delete(app.UIAxesChrom.(a){k}.Children(1))
                    else 
                         app.keepchrom.(a){k} = 0;
                    end
                    plot(app.UIAxesChrom.(a){k}, app.time.(a){k}+shft, app.intensity.(a){k}(:,idxwave), 'DisplayName', string(app.wavelength.(a){k}(idxwave)));
                else % For 2D chromonly data...
                    idxwave = 1;
                    plot(app.UIAxesChrom.(a){k}, app.time.(a){k}+shft, app.intensity.(a){k}(:,idxwave));
                end
                SetColor(app, app.UIAxesChrom.(a){k}) % Always update colors

                for n = 2: length(app.UIAxesChrom.(a){k}.Children) % turn off visibility of other plots
                    app.UIAxesChrom.(a){k}.Children(n).Visible = 'off';
                end 
                    
                disableDefaultInteractivity(app.UIAxesChrom.(a){k}); %disable axis movement for roi lines
                % create an roiline to represent baseline that can be moved by user
                app.hline = images.roi.Line(app.UIAxesChrom.(a){k}, "Position", [app.time.(a){k}(range(1)), app.time.(a){k}(range(1))*base(1)+base(2); app.time.(a){k}(range(2)), app.time.(a){k}(range(2))*base(1)+base(2)], "Deletable",false,"InteractionsAllowed","reshape",'Color', [0 0 0]);
                app.Intlabel.(a){k}.Text = 'Drag baseline to adjust integration';
                % Create OkayButton to confirm user input
                OkayButton = uibutton(app.(strcat('TabGroup', a)).SelectedTab, 'push');
                if app.Label.(a){k}.Text ==  "Scan Data" % for layout purposes scan data ans chromony data will bave button in different location
                    OkayButton.Position = [414 157 100 22];
                else
                    OkayButton.Position = [144 218 220 22];
                end
                OkayButton.ButtonPushedFcn = createCallbackFcn(app, @resume, true);
                OkayButton.Text = 'Okay';
                uiwait(app.HPLCApp) % wait for uiresume after button pushed
        
                enableDefaultInteractivity(app.UIAxesChrom.(a){k}) % return plot interactivity to normal
        
                % Re-do integration...
                % get data
                if app.Label.(a){k}.Text == "Scan Data" % Firs set data as matrix of [ydatavector, xdatavector]
                    num = [app.intensity.(a){k}(:, idxwave), app.time.(a){k}];
                else
                    num = [app.intensity.(a){k}, app.time.(a){k}];
                end
        
                %find new left and right index
                leftside = find(num(:,2) > app.hline.Position(1,1),1,'first'); % get x data closest to left and right sides on roiline
                rightside = find(num(:, 2) > app.hline.Position(2,1),1,'first');
                % draw new line
                base=polyfit([num(leftside,2)  num(rightside,2)],[num(leftside,1)  num(rightside,1)],1); %fit linear between rigth and left side valleys
                app.line.(a){k}(indices(1),:) = base; % Int variable to hold polynomial constants
                app.inttype.(a){k}(indices(1)) = 1; % make the type of integration valleytovalley as represented by 1
                % Adjust data in the table:
                app.UITableChrom.(a){k}.Data(indices(1), 3:5) = [
                    {trapz(num(leftside:rightside,2),num(leftside:rightside,1))-trapz([num(leftside,2) num(rightside,2)], [num(leftside,1) num(rightside,1)])}, ...
                    {(num(find(num(:,2) <= app.UITableChrom.(a){k}.Data{1}, 1, "last"),1)) - (app.UITableChrom.(a){k}.Data{1}*base(1)+base(2))}, ...
                    {strcat(num2str(base(1)), '(x) +  ', num2str(base(2)))}];
                delete(OkayButton) % remove temporary objects
                delete(app.hline)
                for n = 2: length(app.UIAxesChrom.(a){k}.Children) % turn visibility of plots back on
                    app.UIAxesChrom.(a){k}.Children(n).Visible = 'on';
                end 
            end
        end

        % Callback function
        function EditAxisLims(app, event)
            % Callback associated with the axis limits edit field boxes
            % that make it easier to change the axis limits on the plots
            
            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)); % File number

            if app.chromlow.(a){k}.Value >= app.chromupp.(a){k}.Value % Don't allow lower limit to be above upperlimit
                uialert(app.HPLCApp, 'The chromatogram upper axis limit must be larger than the lower limit', 'Error')
                return
            elseif app.Label.(a){k}.Text == "Scan Data" % Same for spec, but this is only done for scan data
                if app.speclow.(a){k}.Value >= app.specupp.(a){k}.Value
                    uialert(app.HPLCApp, 'The chromatogram upper axis limit must be larger than the lower limit', 'Error')
                    return
                end
            end

            % Update all the axis limits
            app.UIAxesChrom.(a){k}.XLim = [app.chromlow.(a){k}.Value, app.chromupp.(a){k}.Value];
            if app.Label.(a){k}.Text == "Scan Data"
                app.UIAxesSpec.(a){k}.XLim = [app.speclow.(a){k}.Value, app.specupp.(a){k}.Value];
            end
        end

        % Close request function: HPLCApp
        function CloseWin(app, event)
            delete(app)
        end

        % Callback function
        function SaveParam(app, event)
            % Save param is a button on each files tab. It will save the
            % parameters that describe the current setup of the spectas,
            % chromatograms, and integrations. The parameters save to an
            % aml file and can be loaded onto any open file using th load
            % parameters button

            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a))); % File number
            
            %Create structure of saved parameters
            Params = struct;
            Params.timeshift = app.TimeShiftEditField.(a){k}.Value;
            Params.Time = app.SelectedTimeEditField.(a){k}.Value; % These are for current working time and wavelength
            Params.Wave = app.SelectedWavelengthEditField.(a){k}.Value;
            Params.lowchrom = app.chromlow.(a){k}.Value; % These parameters are the x axial limits on the plots
            Params.uppchrom = app.chromupp.(a){k}.Value;
            Params.speclow = app.speclow.(a){k}.Value;
            Params.specupp = app.specupp.(a){k}.Value;
            Params.chromwaves = app.SaveChrom.(a){k}.Data; % The saved chromatogram wavelengths
            Params.spectimes = app.SaveSpec.(a){k}.Data; % The saved spectra times
            if size(app.UITableChrom.(a){k}.DisplayData) > 0 % if there is Int data
                Params.Intwl = cell2mat(app.UITableChrom.(a){k}.DisplayData(:, 2)); %col 2 is the wavelength taken at
                Params.IntstartRange = [app.time.(a){k}(app.peakData.(a){k}(:,1))]; % starting index for peak
                Params.IntendRange = [app.time.(a){k}(app.peakData.(a){k}(:,2))]; % ending index for peak
            else % when there are no saved integrations, make these variables empty
                Params.Intwl = [];
                Params.IntstartRange = [];
                Params.IntendRange = [];
            end   

            % Open prompt to save variable to XML file
            [saveFile,savePath] = uiputfile('*.xml','Save Settings', strcat("Parameters - ", app.(strcat('TabGroup', a)).SelectedTab.Title));

            % Write structure to .xml file
            place = fullfile(savePath,saveFile);
            writestruct(Params,place);
        end

        % Callback function
        function LoadParam(app, event)
            % to go along with the above saveparam, the load param will
            % load the variables of a saved xml to the current file window.
            % this includes plotting chromatograms, spectras, and even
            % finding integrations if applicable
            a = app.TabGroup.SelectedTab.Title; % Detector name
            k = find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a))); % File number
            % Get file
            [file, path] = uigetfile('*.xml', 'Select Saved Settings to import');
            Params = readstruct(fullfile(path, file)); % Add file info to structure Params
            try % Use a try catch to catch errors reading the file
                paramsetup(app, a, k, Params)
            catch
                uialert(app.HPLCApp, 'Unable to read parameters', 'Error')
                return
            end
        end

        % Callback function
        function SilentProcessing(app, event)
            % Silent processing allows file chrom, spec, and int data to be analysed directly to
            % exported format using parameters of current tab file
            
            % Gather parameters 
            a = app.TabGroup.SelectedTab.Title; % Detector name of tab params
            k = find(strcmp(app.(strcat('TabGroup', a)).SelectedTab.Title, app.tabtitles.(a)));% File number of tab params

            % Get file of interest
            [getfilename, getpathname] = uigetfile({'*.csv';'*xlsx'}, 'Select HPLC data file to Process.', 'MultiSelect','off');
            if ischar(getfilename) == 1 
                  getfilename = cellstr(getfilename);
            end

            % Allocate data
            getarray = importdata(string(append(getpathname, getfilename)));
            if width(app.intensity.(a){k}) <= 3 % Right now silentprocessing only handles 3D data
                uialert(app.HPLCApp, 'Selected File must be a scan of 3D data', 'Error')
                return
            end
            getintensity = getarray.data(3:end,1:end);
            getwavelength = getarray.data(1,1:end);
            gettime = str2double(getarray.textdata(5:(4+height(getintensity))));
            
            %Create structure of saved parameters
            Params = struct;
            Params.timeshift = app.TimeShiftEditField.(a){k}.Value;
            gettime = gettime + Params.timeshift; % Use timeshift to adjust time array from data above
            Params.Time = app.SelectedTimeEditField.(a){k}.Value;
            Params.Wave = app.SelectedWavelengthEditField.(a){k}.Value;
            Params.lowchrom = app.chromlow.(a){k}.Value;
            Params.uppchrom = app.chromupp.(a){k}.Value;
            Params.speclow = app.speclow.(a){k}.Value;
            Params.specupp = app.specupp.(a){k}.Value;
            Params.chromwaves = reshape(app.SaveChrom.(a){k}.Data, [1, length(app.SaveChrom.(a){k}.Data)]);
            Params.spectimes = reshape(app.SaveSpec.(a){k}.Data, [1, length(app.SaveSpec.(a){k}.Data)]);
            
            % Include working time and wavelength as well
            if ~ismember(app.SelectedTimeEditField.(a){k}.Value, Params.spectimes)
                Params.spectimes = [Params.spectimes, app.SelectedTimeEditField.(a){k}.Value];
            end
            if ~ismember(app.SelectedWavelengthEditField.(a){k}.Value, Params.chromwaves)
                Params.chromwaves = [Params.chromwaves, app.SelectedWavelengthEditField.(a){k}.Value];
            end
            if size(app.UITableChrom.(a){k}.DisplayData) > 0 % Integration parameters should only be saved if there is integration data to save
                Params.Intwl = cell2mat(app.UITableChrom.(a){k}.DisplayData(:, 2)); %col 2 is the wavelength taken at
                Params.IntstartRange = [app.time.(a){k}(app.peakData.(a){k}(:,1))];
                Params.IntendRange = [app.time.(a){k}(app.peakData.(a){k}(:,2))];
            else
                Params.Intwl = [];
                Params.IntstartRange = [];
                Params.IntendRange = [];
            end   

            FileData = struct;
            % Export Spec
            ExportSpec = table();
            if ~isempty(Params.spectimes)
                ExportSpec.Wavelength = (getwavelength)';            
    
                for n = 1:(length(Params.spectimes))
                    if (Params.spectimes(n) < min(gettime)) | (Params.spectimes(n) > max(gettime))
                        continue
                    end
                    colname = strcat('At Time: ', num2str(Params.spectimes(n)));
                    ExportSpec.(colname) = (getintensity(find(gettime <= Params.spectimes(n),1,'last'),:))';
                end
            end
            FileData.Spectras = ExportSpec;

            % Export Chrom
            ExportChrom = table();
            if ~isempty(Params.chromwaves)
                ExportChrom.Time = (gettime)';
    
                for n = 1:(length(Params.chromwaves))
                    if (Params.chromwaves(n) < min(getwavelength)) | (Params.chromwaves(n) > max(getwavelength))
                        continue
                    end
                    colname = strcat('At Wavelength: ', num2str(Params.chromwaves(n)));
                    ExportChrom.(colname) = (getintensity(:,find(getwavelength <= Params.chromwaves(n),1,'last')));
                end 
            end          
            FileData.Chromatograms = ExportChrom;
            
            % Export Integration
            if ~isempty(Params.Intwl)
                for n = 1:length(Params.Intwl)
                    wave = Params.Intwl(n);
                    [~, idxwave] = min(abs(getwavelength-wave));
                    num = [getintensity(:, idxwave), gettime'];
                    if isempty(num)
                        continue
                    end
                    tbl = [];
                    [~,idx] = min(abs(gettime-Params.IntstartRange(n)));
                    low = gettime(idx);
                    [~, idx] = min(abs(gettime-Params.IntendRange(n)));
                    high = gettime(idx);
                    baseline = Int(app, low, high, num, tbl, wave, 0);
                    if ~iscell(app.Intdata)
                        continue
                    end
                    Peaktime = app.Intdata{1};
                    At_Wavelength =  app.Intdata{2};
                    Area = app.Intdata{3};
                    Height = app.Intdata{4};
                    Baseline = app.Intdata(5); 
                    FileData.Integration.(strcat('Peak ', num2str(n))).Summary = table(Peaktime, At_Wavelength, Area, Height, Baseline);
                    
%                     X = (gettime(Params.startRange(n)):(Params.endRange(n)))';
%                     Y = (getline(1)*(X))+ getline(2);
%                     FileData.Integration.(strcat('Peak ', num2str(n))).Baseline_XY_Points = table(X, Y);
                    FileData.Integration.(strcat('Peak ', num2str(n))).Baseline_XY_Points = table(reshape(baseline{1}, [length(baseline{1}), 1]), reshape(baseline{2}, [length(baseline{2}), 1]), 'VariableNames', {'X', 'Y'});
                end
            end

            FileData.Parameters = Params; % Include parameters incase user is interested in replication...

            % Exporting...
            getfilename = cell2mat(getfilename);
            uisave('FileData', strcat("Processed - ", getfilename(1:end-4))); % Save .mat file containing FileData structure          
        end

        % Callback function
        function resume(app, event)
            uiresume(app.HPLCApp)
        end

        % Button pushed function: AlignPeaksButtonConductivity, 
        % ...and 4 other components
        function AlignPeaks(app, event)
             Popup(app); % Align peaks functionality is ran through a seperate app that allows seperate window to open
        end

        % Key press function: UITableConductivity, UITableElectrochemical, 
        % ...and 3 other components
        function DeleteFile(app, event)
           key = event.Key;
            if key == "delete"
                % Gather parameters 
                a = app.TabGroup.SelectedTab.Title; % Detector name of tab params
                tbl = app.(strcat('UITable', a)); % Gather Table
                idx = tbl.Selection(1); % Gets row # AKA File #
                tbl.Data(idx,:) = []; % Remove data from selected table row
                delete(app.(strcat('TabGroup', a)).Children(idx+1))
                % Delete Files info
                app.array.(a)(idx)=[];
                app.time.(a)(idx)=[];
                app.wavelength.(a)(idx)=[];
                app.intensity.(a)(idx)=[];
%                 delete(app.tabs.(a){idx})
                
                app.line.(a)(idx)=[];
                app.peakData.(a)(idx)=[];
                app.inttype.(a)(idx)=[];
                app.baselines.(a)(idx)=[];
                app.UIAxes3D.(a)(idx)=[];
                app.UIAxesSpec.(a)(idx)=[];
                app.UIAxes.(a)(idx)=[];
                app.SelectedTimeEditFieldLabel.(a)(idx) = [];
                app.SelectedTimeEditField.(a)(idx) = [];
                app.SelectedWavelengthEditField.(a)(idx) = [];
                app.SelectedWavelengthEditFieldLabel.(a)(idx) = [];
                app.UITableChrom.(a)(idx) = [];
                app.TimeShiftEditField.(a)(idx) = [];
                app.TimeShiftEditFieldLabel.(a)(idx) = [];
                app.SaveSpectraButton.(a)(idx) = [];
                app.SaveChromatogramButton.(a)(idx) = [];
                app.SaveSpec.(a)(idx) = [];
                app.SaveChrom.(a)(idx) = [];
                app.keepspec.(a)(idx) = [];
                app.keepchrom.(a)(idx) = [];
                app.ExportSpectraButton.(a)(idx) = [];
                app.ExportChromatogramButton.(a)(idx) = [];
                app.speclow.(a)(idx) = [];
                app.specupp.(a)(idx) = [];
                app.SpectraAxisLimitsLabel.(a)(idx) = [];
                app.chromlow.(a)(idx) = [];
                app.chromupp.(a)(idx) = [];
                app.ChromatogramAxisLimitsLabel.(a)(idx) = [];
                app.Intlabel.(a)(idx) = [];
                app.timeROILine.(a)(idx) = [];
                app.waveROILine.(a)(idx) = [];
                app.Label.(a)(idx) = [];
                app.UIAxesChrom.(a)(idx) = [];
                app.tabs.(a)(idx) = [];

                delete(app.(strcat("UIAxesSpec", a)).Children(end+1-idx))
                delete(app.(strcat("UIAxesChrom", a)).Children(end+1-idx))
                
                % Update numbers
                X = app.tabtitles.(a)(idx:app.numfile.(a)-1); %  Get current names
                app.numfile.(a) = app.numfile.(a)-1; % Reduce number of files
                app.tabtitles.(a)(idx)=[]; % Delete idx 
                
                i = 1;
                for k = idx:app.numfile.(a) % Create titles of tabs
%                     app.tabtitles.(a){k} = X{i}; % Rename tabtitles property
%                     app.tabs.(a){k}.Title = X{i}; % and rename the tabs themselves
                   addlistener(app.timeROILine.(a){k}, 'ROIMoved', @(src, event)TLMoved(app, event, k)); % Update Listeners
                    addlistener(app.waveROILine.(a){k}, 'ROIMoved', @(src, event)WLMoved(app, event, k));
                    i = i + 1;
                end
                
                tbl.Data(idx:app.numfile.(a), 1) = (app.tabtitles.(a)(idx:app.numfile.(a)))';
                
            end
        end

        % Cell edit callback: UITableConductivity, UITableElectrochemical, 
        % ...and 3 other components
        function EditFile(app, event)
            indices = event.Indices;
            newData = event.NewData;
            a = app.TabGroup.SelectedTab.Title; % Detector name of tab params
            k = indices(1);
            if indices(2) == 1 % Name Edited
                % Don't allow repeat names
                D = app.tabtitles.(a);
                if ~isempty(find(strcmp(D, newData),1))
                    event.Source.Data{k} = event.PreviousData;
                    return
                end

                % Update tab names
                app.tabtitles.(a){k} = newData;
                app.tabs.(a){k}.Title = newData;
                app.(strcat("UIAxesSpec", a)).Legend.String{k} = newData;
                app.(strcat("UIAxesChrom", a)).Legend.String{k} = newData;
            elseif indices(2) == 3 % Current Time Edited
                app.SelectedTimeEditField.(a){k}.Value = newData;
                
                % Edit accordingly
                shft = app.TimeShiftEditField.(a){k}.Value; % Time shift from origional time axis
            
                if app.keepspec.(a){k} == 0 % Keep spec is false except when current working plot is saved
                    delete(app.UIAxesSpec.(a){k}.Children(1)) % Plot is not saved, so working plot is deleted to be replaced by new spectra
                else % Working plot is saved, bool keepspec is true, working plot added on top of current plots
                    app.keepspec.(a){k} = 0; % Revert bool back to false
                end 
                % Find closest value
                [~, idx] = (min(abs(newData-app.time.(a){k}-shft))); % Get Index of closest time
                value = app.time.(a){k}(idx);
                app.SelectedTimeEditField.(a){k}.Value = value;
                app.(strcat('UITable', a)).Data{k,3} = value; % Allfiles table time change
                app.timeROILine.(a){k}.Position(3) = value; % Chang ROILine position
                app.timeROILine.(a){k}.Position(4) = value;
                plot(app.UIAxesSpec.(a){k}, app.wavelength.(a){k}, app.intensity.(a){k}(idx,:), 'DisplayName',string(app.SelectedTimeEditField.(a){k}.Value));
                SetColor(app, app.UIAxesSpec.(a){k})
                app.(strcat('UIAxesSpec', a)).Children(end-k+1).YData = app.intensity.(a){k}(idx,:);
                SetColor(app, app.(strcat('UIAxesSpec', a)))
            elseif indices(2) == 4 % Current Wavelength Edited
                try
                    app.SelectedWavelengthEditField.(a){k}.Value = newData;
                catch
                    uialert(app.HPLCApp, 'Enter a value within array limits', 'Error')
                end

                % Adjust Accordingly
                shft = app.TimeShiftEditField.(a){k}.Value; % timeshift from origional time axis

                if app.keepchrom.(a){k} == 0 % Bool to determine if chrom was saved
                    delete(app.UIAxesChrom.(a){k}.Children(1)) % If ont saved delete working plot to be replaced
                else % If saved, leave plot and revert bool back to 0
                    app.keepchrom.(a){k} = 0;
                end  
                % Find closest value
                [~,idx] = min(abs(newData-app.wavelength.(a){k})); % Index of closest point in data
                value = app.wavelength.(a){k}(idx);
                app.SelectedWavelengthEditField.(a){k}.Value = value;
                app.(strcat('UITable', a)).Data{k,4} = value; % Allfiles table
                app.waveROILine.(a){k}.Position(1) = value; % RoiLine
                app.waveROILine.(a){k}.Position(2) = value;
                plot(app.UIAxesChrom.(a){k},app.time.(a){k}+shft, app.intensity.(a){k}(:,idx), 'DisplayName', string(app.SelectedWavelengthEditField.(a){k}.Value));
                SetColor(app, app.UIAxesChrom.(a){k})
                app.(strcat('UIAxesChrom', a)).Children(end-k+1).YData = app.intensity.(a){k}(:, idx);
                SetColor(app, app.(strcat('UIAxesChrom', a)))
            end
        end

        % Button pushed function: SaveHPLCFileSetupButton, 
        % ...and 4 other components
        function SaveFileSetup(app, event)
            % Create a structure called Fileset
            Fileset.filename = app.filename; % struct
            Fileset.pathname = app.pathname;
            

            for x = {'PDA', 'PL', 'Conductivity', 'RefractiveIndex', 'Electrochemical'}
                a = x{1};
                
                for k = 1:app.numfile.(a)
                    y = strcat('p',num2str(k));
                    Fileset.tabtitles.(a).(y) = app.tabtitles.(a){k};
                    %Create structure of saved parameters
%                     Params.(a) = struct;
                    Params.(a).(y) = struct;
                    Params.(a).(y).timeshift = app.TimeShiftEditField.(a){k}.Value;
                    Params.(a).(y).Time = app.SelectedTimeEditField.(a){k}.Value; % These are for current working time and wavelength
                    Params.(a).(y).Wave = app.SelectedWavelengthEditField.(a){k}.Value;
                    Params.(a).(y).lowchrom = app.chromlow.(a){k}.Value; % These parameters are the x axial limits on the plots
                    Params.(a).(y).uppchrom = app.chromupp.(a){k}.Value;
                    Params.(a).(y).speclow = app.speclow.(a){k}.Value;
                    Params.(a).(y).specupp = app.specupp.(a){k}.Value;
                    Params.(a).(y).chromwaves = app.SaveChrom.(a){k}.Data; % The saved chromatogram wavelengths
                    Params.(a).(y).spectimes = app.SaveSpec.(a){k}.Data; % The saved spectra times
                    if size(app.UITableChrom.(a){k}.DisplayData) > 0 % if there is Int data
                        Params.(a).(y).Intwl = cell2mat(app.UITableChrom.(a){k}.DisplayData(:, 2)); %col 2 is the wavelength taken at
                        Params.(a).(y).IntstartRange = [app.time.(a){k}(app.peakData.(a){k}(:,1))]; % starting index for peak
                        Params.(a).(y).IntendRange = [app.time.(a){k}(app.peakData.(a){k}(:,2))]; % ending index for peak
                    else % when there are no saved integrations, make these variables empty
                        Params.(a).(y).Intwl = [];
                        Params.(a).(y).IntstartRange = [];
                        Params.(a).(y).IntendRange = [];
                    end   
                end
            end
            Fileset.Params = Params;

            % Open prompt to save variable to XML file
            [saveFile,savePath] = uiputfile('*.xml','Save Fileset', "Fileset");

            % Write structure to .xml file
            place = fullfile(savePath,saveFile);
            writestruct(Fileset,place);

        end

        % Button pushed function: LoadHPLCFileSetupxmlButton, 
        % ...and 4 other components
        function LoadFileset(app, event)
          
            % Get saved .xml
            [file, path] = uigetfile('*.xml', 'Select Fileset to import');
            if isequal(file, 0) % The filename equals zero when cancel is pressed
               return;
            end
            Fileset = readstruct(fullfile(path, file)); % Add file info to structure Params

%             app.filename = Fileset.filename;
            fn = Fileset.filename;
            app.pathname = Fileset.pathname;

            for x = {'PDA', 'PL', 'Conductivity', 'RefractiveIndex', 'Electrochemical'}
                a = x{1};
                tbl = strcat('UITable', a); % Get allfiles table name of current detector tab
                
                if ~isfield(Fileset.filename,a)
                    continue
                end
                
                if isfield(app.filename, a)
                    if ischar(app.filename.(a)) == 1
                          app.filename.(a) = cellstr(app.filename.(a));
                    end
                    app.filename.(a) = [app.filename.(a), cellstr(fn.(a))];
                else
                    app.filename.(a) = cellstr(fn.(a));
                end

                app.numfile.(a) = length(app.filename.(a));
%                 app.filename.(a) = cellstr(app.filename.(a));

                start = height(app.(tbl).DisplayData) + 1; % Starting index of adding files

                for k = start:app.numfile.(a) % Create titles of tabs
                    app.tabtitles.(a){k} = char(Fileset.tabtitles.(a).(strcat('p',num2str(k-start+1))));
                end
                
                if start == 1 % When this is first set of data for the detector
                    % Data in table contains file number, filename, timepoint, wavelength
                    app.(tbl).Data = [app.tabtitles.(a)', (app.filename.(a))', cell(1,app.numfile.(a))', cell(1,app.numfile.(a))'];
                    app.(tbl).ColumnWidth = {'fit', 'auto', 'auto', 'auto'};
                else % Adding data to table 
                    app.(tbl).Data = [app.(tbl).Data;app.tabtitles.(a)(start:app.numfile.(a))', (cellstr(fn.(a)))', cell(1,app.numfile.(a)-start+1)', cell(1,app.numfile.(a)-start+1)'];                
                end                
                
                hold(app.(strcat('UIAxesSpec', a)), "on") % keep hold on so plots are kept as new ones are added
                hold(app.(strcat('UIAxesChrom', a)), "on")
                for k=start:app.numfile.(a) % Set up of each added file
                     app.array.(a){k} = importdata(append(app.pathname, app.filename.(a){k-start+1})); % Entire files info
                     datasetup(app, a, k)
                     % Create Tabs
                     app.tabs.(a){k} = uitab(app.(strcat('TabGroup', a)), "Title", app.tabtitles.(a){k});
                     tabsetup(app, k, a) % Funtion will add uielements for each tab
                     Params = Fileset.Params.(a).(strcat('p',num2str(k-start+1)));
                    try % Use a try catch to catch errors reading the file
                        paramsetup(app, a, k, Params)
                    catch
                        uialert(app.HPLCApp, 'Unable to read parameters', 'Error')
                        return
                    end
                end
                hold(app.(strcat('UIAxesSpec', a)), "off") % Hold no longer required
                hold(app.(strcat('UIAxesChrom', a)), "off")
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HPLCApp = uifigure('Visible', 'off');
            app.HPLCApp.Name = 'MATLAB App';
            app.HPLCApp.CloseRequestFcn = createCallbackFcn(app, @CloseWin, true);
            app.TabGroup = uitabgroup(app.HPLCApp);
            app.PDATab = uitab(app.TabGroup);
            app.PDATab.Title = 'PDA';
            app.TabGroupPDA = uitabgroup(app.PDATab);
            app.AllFilesTab = uitab(app.TabGroupPDA);
            app.AllFilesTab.Title = 'All Files';
            app.UIAxesChromPDA = uiaxes(app.AllFilesTab);
            title(app.UIAxesChromPDA, 'Chromatogram')
            xlabel(app.UIAxesChromPDA, 'Time')
            ylabel(app.UIAxesChromPDA, 'Intensity')
            app.UIAxesSpecPDA = uiaxes(app.AllFilesTab);
            title(app.UIAxesSpecPDA, 'Spectra')
            xlabel(app.UIAxesSpecPDA, 'Wavelength')
            ylabel(app.UIAxesSpecPDA, 'Intensity')
            app.UITablePDA = uitable(app.AllFilesTab);
            app.UITablePDA.ColumnName = {''; 'Filename'; 'Time'; 'Wavelength'};
            app.UITablePDA.RowName = {};
            app.UITablePDA.SelectionType = 'row';
            app.UITablePDA.CellEditCallback = createCallbackFcn(app, @EditFile, true);
            app.UITablePDA.KeyPressFcn = createCallbackFcn(app, @DeleteFile, true);
            app.LoadFilesButtonPDA = uibutton(app.AllFilesTab, 'push');
            app.LoadFilesButtonPDA.ButtonPushedFcn = createCallbackFcn(app, @Loadfiles, true);
            app.LoadFilesButtonPDA.Text = 'Load Files';
            app.AlignPeaksButtonPDA = uibutton(app.AllFilesTab, 'push');
            app.AlignPeaksButtonPDA.ButtonPushedFcn = createCallbackFcn(app, @AlignPeaks, true);
            app.AlignPeaksButtonPDA.Text = 'Align Peaks';
            app.ExportSpectraButtonPDA = uibutton(app.AllFilesTab, 'push');
            app.ExportSpectraButtonPDA.ButtonPushedFcn = createCallbackFcn(app, @ExportSpec, true);
            app.ExportSpectraButtonPDA.Text = 'Export Spectra';
            app.ExportChromButtonPDA = uibutton(app.AllFilesTab, 'push');
            app.ExportChromButtonPDA.ButtonPushedFcn = createCallbackFcn(app, @ExportChrom, true);
            app.ExportChromButtonPDA.Text = 'Export Chrom';
            app.ExportSpecLabelPDA = uilabel(app.AllFilesTab);
            app.ExportSpecLabelPDA.Visible = 'off';
            app.ExportChromLabelPDA = uilabel(app.AllFilesTab);
            app.ExportChromLabelPDA.Visible = 'off';
            app.SaveHPLCFileSetupButton = uibutton(app.AllFilesTab, 'push');
            app.SaveHPLCFileSetupButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileSetup, true);
            app.SaveHPLCFileSetupButton.Text = 'Save HPLC File Setup';
            app.LoadHPLCFileSetupxmlButton = uibutton(app.AllFilesTab, 'push');
            app.LoadHPLCFileSetupxmlButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileset, true);
            app.LoadHPLCFileSetupxmlButton.Text = 'Load HPLC File Setup (.xml)';
            app.PLTab = uitab(app.TabGroup);
            app.PLTab.Title = 'PL';
            app.TabGroupPL = uitabgroup(app.PLTab);
            app.AllFilesTab_2 = uitab(app.TabGroupPL);
            app.AllFilesTab_2.Title = 'All Files';
            app.UIAxesChromPL = uiaxes(app.AllFilesTab_2);
            title(app.UIAxesChromPL, 'Chromatogram')
            xlabel(app.UIAxesChromPL, 'Time')
            ylabel(app.UIAxesChromPL, 'Intensity')
            app.UIAxesSpecPL = uiaxes(app.AllFilesTab_2);
            title(app.UIAxesSpecPL, 'Spectra')
            xlabel(app.UIAxesSpecPL, 'Wavelength')
            ylabel(app.UIAxesSpecPL, 'Intensity')
            app.UITablePL = uitable(app.AllFilesTab_2);
            app.UITablePL.ColumnName = {''; 'Filename'; 'Time'; 'Wavelength'};
            app.UITablePL.RowName = {};
            app.UITablePL.CellEditCallback = createCallbackFcn(app, @EditFile, true);
            app.UITablePL.KeyPressFcn = createCallbackFcn(app, @DeleteFile, true);
            app.LoadFilesButtonPL = uibutton(app.AllFilesTab_2, 'push');
            app.LoadFilesButtonPL.ButtonPushedFcn = createCallbackFcn(app, @Loadfiles, true);
            app.LoadFilesButtonPL.Text = 'Load Files';
            app.AlignPeaksButtonPL = uibutton(app.AllFilesTab_2, 'push');
            app.AlignPeaksButtonPL.ButtonPushedFcn = createCallbackFcn(app, @AlignPeaks, true);
            app.AlignPeaksButtonPL.Text = 'Align Peaks';
            app.ExportSpectraButtonPL = uibutton(app.AllFilesTab_2, 'push');
            app.ExportSpectraButtonPL.ButtonPushedFcn = createCallbackFcn(app, @ExportSpec, true);
            app.ExportSpectraButtonPL.Text = 'Export Spectra';
            app.ExportChromButtonPL = uibutton(app.AllFilesTab_2, 'push');
            app.ExportChromButtonPL.ButtonPushedFcn = createCallbackFcn(app, @ExportChrom, true);
            app.ExportChromButtonPL.Text = 'Export Chrom';
            app.ExportSpecLabelPL = uilabel(app.AllFilesTab_2);
            app.ExportSpecLabelPL.Visible = 'off';
            app.ExportChromLabelPL = uilabel(app.AllFilesTab_2);
            app.ExportChromLabelPL.Visible = 'off';
            app.SaveHPLCFileSetupButton_2 = uibutton(app.AllFilesTab_2, 'push');
            app.SaveHPLCFileSetupButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveFileSetup, true);
            app.SaveHPLCFileSetupButton_2.Text = 'Save HPLC File Setup';
            app.LoadHPLCFileSetupxmlButton_2 = uibutton(app.AllFilesTab_2, 'push');
            app.LoadHPLCFileSetupxmlButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadFileset, true);
            app.LoadHPLCFileSetupxmlButton_2.Text = 'Load HPLC File Setup (.xml)';
            app.ConductivityTab = uitab(app.TabGroup);
            app.ConductivityTab.Title = 'Conductivity';
            app.TabGroupConductivity = uitabgroup(app.ConductivityTab);
            app.AllFilesTab_3 = uitab(app.TabGroupConductivity);
            app.AllFilesTab_3.Title = 'All Files';
            app.UIAxesChromConductivity = uiaxes(app.AllFilesTab_3);
            title(app.UIAxesChromConductivity, 'Chromatogram')
            xlabel(app.UIAxesChromConductivity, 'Time')
            ylabel(app.UIAxesChromConductivity, 'Intensity')
            app.UIAxesSpecConductivity = uiaxes(app.AllFilesTab_3);
            title(app.UIAxesSpecConductivity, 'Spectra')
            xlabel(app.UIAxesSpecConductivity, 'Wavelength')
            ylabel(app.UIAxesSpecConductivity, 'Intensity')
            app.UITableConductivity = uitable(app.AllFilesTab_3);
            app.UITableConductivity.ColumnName = {''; 'Filename'; 'Time'; 'Wavelength'};
            app.UITableConductivity.RowName = {};
            app.UITableConductivity.CellEditCallback = createCallbackFcn(app, @EditFile, true);
            app.UITableConductivity.KeyPressFcn = createCallbackFcn(app, @DeleteFile, true);
            app.LoadFilesButtonConductivity = uibutton(app.AllFilesTab_3, 'push');
            app.LoadFilesButtonConductivity.ButtonPushedFcn = createCallbackFcn(app, @Loadfiles, true);
            app.LoadFilesButtonConductivity.Text = 'Load Files';
            app.AlignPeaksButtonConductivity = uibutton(app.AllFilesTab_3, 'push');
            app.AlignPeaksButtonConductivity.ButtonPushedFcn = createCallbackFcn(app, @AlignPeaks, true);
            app.AlignPeaksButtonConductivity.Text = 'Align Peaks';
            app.ExportSpectraButtonConductivity = uibutton(app.AllFilesTab_3, 'push');
            app.ExportSpectraButtonConductivity.ButtonPushedFcn = createCallbackFcn(app, @ExportSpec, true);
            app.ExportSpectraButtonConductivity.Text = 'Export Spectra';
            app.ExportChromButtonConductivity = uibutton(app.AllFilesTab_3, 'push');
            app.ExportChromButtonConductivity.ButtonPushedFcn = createCallbackFcn(app, @ExportChrom, true);
            app.ExportChromButtonConductivity.Text = 'Export Chrom';
            app.ExportSpecLabelConductivity = uilabel(app.AllFilesTab_3);
            app.ExportSpecLabelConductivity.Visible = 'off';
            app.ExportChromLabelConductivity = uilabel(app.AllFilesTab_3);
            app.ExportChromLabelConductivity.Visible = 'off';
            app.SaveHPLCFileSetupButton_5 = uibutton(app.AllFilesTab_3, 'push');
            app.SaveHPLCFileSetupButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveFileSetup, true);
            app.SaveHPLCFileSetupButton_5.Text = 'Save HPLC File Setup';
            app.LoadHPLCFileSetupxmlButton_5 = uibutton(app.AllFilesTab_3, 'push');
            app.LoadHPLCFileSetupxmlButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadFileset, true);
            app.LoadHPLCFileSetupxmlButton_5.Text = 'Load HPLC File Setup (.xml)';
            app.RefractiveIndexTab = uitab(app.TabGroup);
            app.RefractiveIndexTab.Title = 'RefractiveIndex';
            app.TabGroupRefractiveIndex = uitabgroup(app.RefractiveIndexTab);
            app.AllFilesRefractiveIndex = uitab(app.TabGroupRefractiveIndex);
            app.AllFilesRefractiveIndex.Title = 'All Files';
            app.UIAxesChromRefractiveIndex = uiaxes(app.AllFilesRefractiveIndex);
            title(app.UIAxesChromRefractiveIndex, 'Chromatogram')
            xlabel(app.UIAxesChromRefractiveIndex, 'Time')
            ylabel(app.UIAxesChromRefractiveIndex, 'Intensity')
            app.UIAxesSpecRefractiveIndex = uiaxes(app.AllFilesRefractiveIndex);
            title(app.UIAxesSpecRefractiveIndex, 'Spectra')
            xlabel(app.UIAxesSpecRefractiveIndex, 'Wavelength')
            ylabel(app.UIAxesSpecRefractiveIndex, 'Intensity')
            app.UITableRefractiveIndex = uitable(app.AllFilesRefractiveIndex);
            app.UITableRefractiveIndex.ColumnName = {''; 'Filename'; 'Time'; 'Wavelength'};
            app.UITableRefractiveIndex.RowName = {};
            app.UITableRefractiveIndex.CellEditCallback = createCallbackFcn(app, @EditFile, true);
            app.UITableRefractiveIndex.KeyPressFcn = createCallbackFcn(app, @DeleteFile, true);
            app.LoadFilesButtonRefractiveIndex = uibutton(app.AllFilesRefractiveIndex, 'push');
            app.LoadFilesButtonRefractiveIndex.ButtonPushedFcn = createCallbackFcn(app, @Loadfiles, true);
            app.LoadFilesButtonRefractiveIndex.Text = 'Load Files';
            app.AlignPeaksButtonRefractiveIndex = uibutton(app.AllFilesRefractiveIndex, 'push');
            app.AlignPeaksButtonRefractiveIndex.ButtonPushedFcn = createCallbackFcn(app, @AlignPeaks, true);
            app.AlignPeaksButtonRefractiveIndex.Text = 'Align Peaks';
            app.ExportSpectraButtonRefractiveIndex = uibutton(app.AllFilesRefractiveIndex, 'push');
            app.ExportSpectraButtonRefractiveIndex.Text = 'Export Spectra';
            app.ExportChromButtonRefractiveIndex = uibutton(app.AllFilesRefractiveIndex, 'push');
            app.ExportChromButtonRefractiveIndex.Text = 'Export Chrom';
            app.ExportSpecLabelRefractiveIndex = uilabel(app.AllFilesRefractiveIndex);
            app.ExportSpecLabelRefractiveIndex.Visible = 'off';
            app.ExportChromLabelRefractiveIndex = uilabel(app.AllFilesRefractiveIndex);
            app.ExportChromLabelRefractiveIndex.Visible = 'off';
            app.SaveHPLCFileSetupButton_4 = uibutton(app.AllFilesRefractiveIndex, 'push');
            app.SaveHPLCFileSetupButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveFileSetup, true);
            app.SaveHPLCFileSetupButton_4.Text = 'Save HPLC File Setup';
            app.LoadHPLCFileSetupxmlButton_4 = uibutton(app.AllFilesRefractiveIndex, 'push');
            app.LoadHPLCFileSetupxmlButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadFileset, true);
            app.LoadHPLCFileSetupxmlButton_4.Text = 'Load HPLC File Setup (.xml)';
            app.ElectrochemicalTab = uitab(app.TabGroup);
            app.ElectrochemicalTab.Title = 'Electrochemical';
            app.TabGroupElectrochemical = uitabgroup(app.ElectrochemicalTab);
            app.AllFilesTabElectrochemical = uitab(app.TabGroupElectrochemical);
            app.AllFilesTabElectrochemical.Title = 'All Files';
            app.UIAxesChromElectrochemical = uiaxes(app.AllFilesTabElectrochemical);
            title(app.UIAxesChromElectrochemical, 'Chromatogram')
            xlabel(app.UIAxesChromElectrochemical, 'Time')
            ylabel(app.UIAxesChromElectrochemical, 'Intensity')
            app.UIAxesSpecElectrochemical = uiaxes(app.AllFilesTabElectrochemical);
            title(app.UIAxesSpecElectrochemical, 'Spectra')
            xlabel(app.UIAxesSpecElectrochemical, 'Wavelength')
            ylabel(app.UIAxesSpecElectrochemical, 'Intensity')
            app.UITableElectrochemical = uitable(app.AllFilesTabElectrochemical);
            app.UITableElectrochemical.ColumnName = {''; 'Filename'; 'Time'; 'Wavelength'};
            app.UITableElectrochemical.RowName = {};
            app.UITableElectrochemical.CellEditCallback = createCallbackFcn(app, @EditFile, true);
            app.UITableElectrochemical.KeyPressFcn = createCallbackFcn(app, @DeleteFile, true);
            app.LoadFilesButtonElectrochemical = uibutton(app.AllFilesTabElectrochemical, 'push');
            app.LoadFilesButtonElectrochemical.ButtonPushedFcn = createCallbackFcn(app, @Loadfiles, true);
            app.LoadFilesButtonElectrochemical.Text = 'Load Files';
            app.AlignPeaksButtonElectrochemical = uibutton(app.AllFilesTabElectrochemical, 'push');
            app.AlignPeaksButtonElectrochemical.ButtonPushedFcn = createCallbackFcn(app, @AlignPeaks, true);
            app.AlignPeaksButtonElectrochemical.Text = 'Align Peaks';
            app.ExportSpectraButtonElectrochemical = uibutton(app.AllFilesTabElectrochemical, 'push');
            app.ExportSpectraButtonElectrochemical.Text = 'Export Spectra';
            app.ExportChromButtonElectrochemical = uibutton(app.AllFilesTabElectrochemical, 'push');
            app.ExportChromButtonElectrochemical.Text = 'Export Chrom';
            app.ExportSpecLabelElectrochemical = uilabel(app.AllFilesTabElectrochemical);
            app.ExportSpecLabelElectrochemical.Visible = 'off';
            app.ExportChromLabelElectrochemical = uilabel(app.AllFilesTabElectrochemical);
            app.ExportChromLabelElectrochemical.Visible = 'off';
            app.SaveHPLCFileSetupButton_3 = uibutton(app.AllFilesTabElectrochemical, 'push');
            app.SaveHPLCFileSetupButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveFileSetup, true);
            app.SaveHPLCFileSetupButton_3.Text = 'Save HPLC File Setup';
            app.LoadHPLCFileSetupxmlButton_3 = uibutton(app.AllFilesTabElectrochemical, 'push');
            app.LoadHPLCFileSetupxmlButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadFileset, true);
            app.LoadHPLCFileSetupxmlButton_3.Text = 'Load HPLC File Setup (.xml)';
            % Show the figure after all components are created
            app.HPLCApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HPLCwithnewintegration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HPLCApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HPLCApp)
        end
    end
end