classdef PeakIntegrator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IntFig                          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        VisualizeButtonGroup            matlab.ui.container.ButtonGroup
        AreasButton                     matlab.ui.control.RadioButton
        BaselinesButton                 matlab.ui.control.RadioButton
        MinValleyProminanceSliderLabel  matlab.ui.control.Label
        MethodofIntegrationButtonGroup  matlab.ui.container.ButtonGroup
        VerticalDropButton              matlab.ui.control.RadioButton
        ValleytoValleyButton            matlab.ui.control.RadioButton
        MinValleyProminanceSlider       matlab.ui.control.Slider
        MinPeakProminanceSlider         matlab.ui.control.Slider
        MinPeakProminanceLabel          matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        InstructionsLabel               matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        AddIntegrationstosaveddataButton  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        IntAx                           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp % Main app
        colors % For the colors of plots
        wavelength % Wavelength of working data
        xdata % Imported X data(times)
        ydata % Imported Y data(Intensities)
        peakplt % Holds plot of peaks for easy updating
        plotedits % Visuals to show integration on plt
        a % The Detector from mainapp
        k % The file number from mainapp

        peaks % peak heights
        pidx % time of peaks
        prom % prominance of peaks
        width % width of peaks

        lims % The edges of each peak in a #ofpeaks by 2 matrix holding (start, end) pairs
        line % Base line coefficients used for sending data back to mainapp
        baselines % Holds x and y points of baselines in cell structure where position n,1 is x points of peak n and n,2 is y points of peak n
        gaussidx % indices of any gauss's
        gaussorder % order of gauss's
    end
    
    methods (Access = private)
        
        function PeakLimits(app) %find the edges of the peaks
            [~, pointoptionsidx] = findpeaks(-app.ydata, "MinPeakProminence",app.MinValleyProminanceSlider.Value); % Find all valleys
            pointoptionsidx = [1, pointoptionsidx, length(app.ydata)]; % Include first and last value
            pointoptionstime = app.xdata(pointoptionsidx); % Get time at the index of each possible endpoint

            limits = zeros(length(app.peaks),2); % Initialize limits matrix
            limits(1,1) = pointoptionsidx(find(pointoptionstime<app.pidx(1), 1, "last")); % Set first value
            for n = 1:length(app.peaks)-1
                % Find the first point on either side of the peak
                limits(n,2) = pointoptionsidx(find(pointoptionstime > app.pidx(n) & pointoptionstime < app.pidx(n+1), 1, "first"));
                limits(n+1,1) = pointoptionsidx(find(pointoptionstime > app.pidx(n) & pointoptionstime < app.pidx(n+1), 1, "last"));
            end
            limits(end,2) = pointoptionsidx(find(pointoptionstime>app.pidx(end), 1, "first")); % Set last value
            app.lims = limits; % Add to property to be used
        end
        
        function val2val(app) % Integration method: Valley to valley
            % First delete everything
            delete(app.plotedits)
            app.plotedits = [];
            app.UITable.Data = {};

            for n = 1:length(app.peaks) % Each iter is for a peak
                % Get limits of peak
                leftside = app.lims(n,1);
                rightside = app.lims(n,2);

                % Draw lines and visualize
                base=polyfit([app.xdata(leftside)  app.xdata(rightside)],[app.ydata(leftside)  app.ydata(rightside)],1); %fit linear between rigth and left side valleys
                lines = app.xdata(leftside:rightside)*base(1)+base(2);
                app.line(n,:) = base; % Save to variable in case of saving data to mainapp
                switch app.VisualizeButtonGroup.SelectedObject.Text % Determine integration type
                    case 'Baselines'
                        app.plotedits(n,1) = plot(app.IntAx, app.xdata(leftside:rightside), lines);
%                         , "Color",app.colors(3,:));
                    case 'Areas'
%                         if mod(n, 2) == 0
%                             c = app.colors(3,:);
%                         else
%                             c = app.colors(4,:);
%                         end
                        app.plotedits(n,1) = area(app.IntAx, app.xdata(leftside:rightside), app.ydata(leftside:rightside));
%                         , "FaceColor",c, "EdgeColor", app.colors(1,:));
                        app.plotedits(n,2) = area(app.IntAx, app.xdata(leftside:rightside), lines, 'FaceColor','w');
%                         , "EdgeColor", app.colors(1,:));
                end

                % Integrate
                app.baselines{n,1} = app.xdata(leftside:rightside);
                app.baselines{n,2} = lines;
                peakarea = trapz(app.xdata(leftside:rightside),app.ydata(leftside:rightside))-trapz([app.xdata(leftside) app.xdata(rightside) ],[app.ydata(leftside) app.ydata(rightside)]);
%                 app.UITable.Data(n,:) = [{1}, {app.pidx(n)}, {app.wavelength}, {peakarea}, {(app.peaks(n)) - (app.pidx(n)*base(1)+base(2))}, {strcat(num2str(base(1)), '(x) +  ', num2str(base(2)))}];
                app.UITable.Data(n,:) = [{''}, {app.pidx(n)}, {app.wavelength}, {peakarea}, {(app.peaks(n)) - (app.pidx(n)*base(1)+base(2))}, {strcat(num2str(base(1)), '(x) +  ', num2str(base(2)))}];
            end 
        end
        
        function vert(app) % Integration method: Vertical Drop
            % Remove extra stuff from plot
            delete(app.plotedits)
            app.plotedits = [];

            % Get any peaks that are selected to be gaussians
            vals = find([app.UITable.Data{:,1}]); % These are index of selected peaks for gauss
            [~, order] = sort([app.UITable.Data{vals, 1}], "ascend"); % Order them from peak 1 to peak last
%             testsort = sort([app.UITable.Data{vals, 1}], "ascend")
%             [app.UITable.Data{vals, 1}]
%             order
            base = zeros(size(app.xdata)); % Initialize a variable base to hold the working baseline (Other integrations will take on the baseline of intersecting gauss)
            bl = cell(length(app.peaks),2);

            % Find Gaussian
            for m = 1: length(vals)
                pnum = vals(order(m)); % Start with the peak that is first in gauss peak order
                leftside = app.lims(pnum,1);
                rightside = app.lims(pnum,2);
                gauss = fit(app.xdata(leftside:rightside)', app.ydata(leftside:rightside)', 'gauss1');         % Create Gaussian equation
                y = gauss.a1*exp(-((app.xdata-gauss.b1)/gauss.c1).^2); % Get points of the gaussian
%                 in = find(y > base); % find the index of all points above the working base
                app.line(pnum, :) = [0, 0]; % All will have baseline of zero
                newy = [y(1:leftside), app.ydata((leftside+1):(rightside-1)), y(rightside:end)]; % y line of peak data between boundarys and gauss data outside boundaries
                indy = rmoutliers(find(newy > base)); % Indices of all ydata above the moving base
                switch app.VisualizeButtonGroup.SelectedObject.Text
                    case 'Baselines'
                        app.plotedits(pnum,1) = plot(app.IntAx, app.xdata(indy), newy(indy));
        %                         , "Color",app.colors(3,:));
                        hold(app.IntAx, "on")
                    case 'Areas'
%                             delete(app.plotedits(pnum,2))
        %                     if mod(pnum, 2) == 0
        %                         c = app.colors(3,:);
        %                     else 
        %                         c = app.colors(4,:);
        %                     end 
                        app.plotedits(pnum,1) = area(app.IntAx, app.xdata, newy);
        %                         , "FaceColor",c, "EdgeColor", app.colors(1,:));
        %                     app.plotedits(pnum,2) = area(app.IntAx, app.xdata(leftside:rightside), y(leftside:rightside), 'FaceColor','w');
                end 
                bl{pnum,1} = app.xdata(indy);
                bl{pnum,2} = base(indy);
                peakarea = trapz(app.xdata(indy), y(indy)-base(indy)); % integrate gauss peak that is above working base
                base = max(base, y); % update the working base with new gauss info
                text ={strcat('peak ',num2str(m),' under Gauss: ', num2str(gauss.a1), '*exp(-((x-', num2str(gauss.b1), ')/', num2str(gauss.c1), ')^2)')};  % Write a label for the baseline for the table
                app.UITable.Data(pnum, 2:end) = [{gauss.b1}, {app.wavelength}, {peakarea}, {gauss.a1}, text];
            end 

            % Find when that line intersects stuff
            for n = 1:length(app.peaks)
                if ismember(n,vals)
                    continue
                end
                leftside = app.lims(n,1);
                rightside = app.lims(n,2);
                % draw lines and visualize
                app.line(n, :) = [0, 0];
                switch app.VisualizeButtonGroup.SelectedObject.Text
                    case 'Baselines'
                        if exist("y", "var") % y exists when there wer gauss to integrate above
                            app.plotedits(n, 1:2) = plot(app.IntAx, [app.xdata(leftside)  app.xdata(leftside)], [app.ydata(leftside)  base(leftside)], [app.xdata(rightside)  app.xdata(rightside)], [app.ydata(rightside)  base(rightside)]);
    %                         , "Color",app.colors(3,:));
                        else
                            app.plotedits(n, 1:2) = plot(app.IntAx, [app.xdata(leftside)  app.xdata(leftside)], [app.ydata(leftside)  0], [app.xdata(rightside)  app.xdata(rightside)], [app.ydata(rightside)  0], "Color",app.colors(3,:));
                        end
                        hold(app.IntAx, "on")
                    case 'Areas'
    %                         if mod(n, 2) == 0
    %                             c = app.colors(3,:);
    %                         else
    %                             c = app.colors(4,:);
    %                         end
                        app.plotedits(n,1) = area(app.IntAx, app.xdata(leftside:rightside), app.ydata(leftside:rightside));
    %                             , "FaceColor",c, "EdgeColor", app.colors(1,:));
                        hold(app.IntAx, "on")
    %                         app.plotedits(n,2) = area(app.IntAx, app.xdata(leftside:rightside), y(leftside:rightside), "FaceColor",'w');
                end
                
                % Integrate
                bl{n,1} = app.xdata(leftside:rightside);
                bl{n,2} = base(leftside:rightside);
                if exist("y", "var") % y exists when there wer gauss to integrate above
                    peakarea = trapz(app.xdata(leftside:rightside),app.ydata(leftside:rightside))-trapz(app.xdata(leftside:rightside),base(leftside:rightside));
                    app.UITable.Data(n,:) = [{false(1)}, {app.pidx(n)}, {app.wavelength}, {peakarea}, {(app.peaks(n)-y(app.xdata == app.pidx(n)))}, {0}];
                else
                    peakarea = trapz(app.xdata(leftside:rightside),app.ydata(leftside:rightside));
                    app.UITable.Data(n,:) = [{false(1)}, {app.pidx(n)}, {app.wavelength}, {peakarea}, {(app.peaks(n))}, {0}];
                end
            end
            for m = length(vals):-1:1
                pnum = vals(order(m));
                uistack(app.plotedits(pnum,1),'top'); % When areas are plotted the plot edits for visualization need to be re-arranged
            end
%                 plot(app.IntAx, app.xdata, base)
    
            app.plotedits(end+1,1) = plot(app.IntAx, app.xdata, zeros(1, length(app.xdata)));
            app.baselines = bl;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, a, k, wave, colors)
            app.CallingApp = mainapp;
            app.colors = colors;
    
%             colororder
%             app.colors = (colororder);
            
            app.wavelength = wave;
            app.xdata = mainapp.UIAxesChrom.(a){k}.Children(1).XData;
            app.ydata = mainapp.UIAxesChrom.(a){k}.Children(1).YData;
            plot(app.IntAx, app.xdata, app.ydata, "Color", app.colors(1,:));
            hold(app.IntAx, "on")

            app.a = a;
            app.k = k;

            app.ydata(find(~isfinite(app.xdata))) = [];
            app.xdata(find(~isfinite(app.xdata))) = [];
            rmmissing(app.xdata);
            rmmissing(app.ydata);

            [app.peaks, app.pidx, app.width, app.prom] = findpeaks(app.ydata, app.xdata, "Threshold", 0, "MinPeakProminence", 0.2*range(app.ydata));
            app.peakplt = plot(app.IntAx, app.pidx, app.peaks, 'o');
%             , "Color",app.colors(2,:));

            % Set up sliders
            if length(app.peaks) > 1
                app.MinPeakProminanceSlider.Limits = [0, range(app.peaks)];
                app.MinValleyProminanceSlider.Limits = [0, range(app.peaks)];
            else
                app.MinPeakProminanceSlider.Limits = [0, app.peaks];
                app.MinValleyProminanceSlider.Limits = [0, app.peaks];
            end
            app.MinPeakProminanceSlider.Value = 0.2*range(app.ydata);
            app.MinValleyProminanceSlider.Value = 0;

            PeakLimits(app)
            val2val(app)
        end

        % Callback function: MinPeakProminanceSlider, 
        % ...and 2 other components
        function UpdatePeakPrecision(app, event)
            value1 = app.MinPeakProminanceSlider.Value;
            try
                vals = find([app.UITable.Data{:,1}]); % get the gauss peaks indexes in previous setup if any
                times = app.pidx(vals); % get the current time that gauss peaks occur in previous set up
                if ~isempty(times)
                    num = [app.UITable.Data{vals, 1}]; % Get order number associated with each gauss
                end
                % Find peaks as normal
                [app.peaks, app.pidx, app.width, app.prom] = findpeaks(app.ydata, app.xdata,"MinPeakProminence", value1, "Annotate","extents",'WidthReference','halfheight');
                app.peakplt.XData = app.pidx;
                app.peakplt.YData = app.peaks;
                PeakLimits(app)
                selectedButton = app.MethodofIntegrationButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case 'Valley to Valley'
                        val2val(app) % Run valley to calley integration function
                    case 'Vertical Drop'
                        for m = 1:(length(app.peaks))
                            app.UITable.Data(m,1) = {false(1)}; % Reset all gauss to off
                        end
                        if ~isempty(times) % if there are any gauss from previous
                            for m = 1:length(times)

                                app.UITable.Data(find(app.pidx == times(m)),1) = {num(m)}; % find where those gauss peaks occured and update new data with this info
                            end 
                        end

                        vert(app) % Run Vertical integration function
                end
            catch
                uialert(app.IntFig, 'These Settings could not be computed', 'Error')
                event.Source.Value = event.PreviousValue;
            end
        end

        % Button pushed function: CloseButton
        function close(app, event)
            delete(app)
        end

        % Selection changed function: MethodofIntegrationButtonGroup
        function IntTypeSelect(app, event)
            selectedButton = app.MethodofIntegrationButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Valley to Valley'
                    val2val(app) % Run valley to valley function
                case 'Vertical Drop'
                    vert(app)   % run vertical integration function
            end
        end

        % Button pushed function: AddIntegrationstosaveddataButton
        function Savedata(app, event)
            selectedButton = app.MethodofIntegrationButtonGroup.SelectedObject;
            
            if isempty(app.UITable.Selection)
                switch selectedButton.Text
                    case 'Valley to Valley'
                        type = ones(height(app.UITable.Data));
                    case 'Vertical Drop'
                        type = zeros(height(app.UITable.Data));
                        for n = 1: height(app.UITable.Data)
                            if app.UITable.Data{n,end} == 0
                                type(n) = 2;
                            else
                                type(n) = 3;
                            end
                        end
                end  
                SaveIntStuff(app.CallingApp, app.a, app.k, app.UITable.Data(:,2:end), app.lims, app.line, type, app.baselines)
            else
                app.UITable.Selection
                length(app.UITable.Selection)
                switch selectedButton.Text
                    case 'Valley to Valley'
                        type = ones(length(app.UITable.Selection));
                        
                    case 'Vertical Drop'
                        type = zeros(length(app.UITable.Selection));
                        type(:) = 2;
                end
                SaveIntStuff(app.CallingApp, app.a, app.k, app.UITable.Data(app.UITable.Selection, 2:end), app.lims(app.UITable.Selection, :), app.line(app.UITable.Selection, :), type, app.baselines(app.UITable.Selection, :))
            end
        end

        % Cell edit callback: UITable
        function AddGauss(app, event)
            % The cell edit callback is used for user check marks in the
            % UITable to add a gaussian. The only editable component of the
            % chromatogram is the Gauss checks

            % The gauss construction works by finding the gauss in order of
            % user selection. Each peak will contain file data for inputs
            % within peak boundaries as described and skim the end of the
            % peak using the gauss. The gauss will continue until it hits
            % zero or an already present peak. This is called the working
            % base line and is denoted as variable, base.

            indices = event.Indices; % Position of cell edited
            newData = event.NewData; % Either 1 or 0 for checked data

            app.UITable.Data(indices(1), 1) = {NaN}; % To edit display of check to numbering first make nan
            app.UITable.Data(indices(1), 1) = {length(find([app.UITable.Data{:,1}]))}; % Change checked box to ordered number of gauss peak
            vals = find([app.UITable.Data{:,1}]); % Get index of all checked gauss
            [~, order] = sort([app.UITable.Data{vals, 1}], "ascend"); % Get index of gauss in sorted order, so first index is gauss 1 and last index is last gauss
            base = zeros(size(app.xdata)); % initialize matrix for a working base line that changes with each newplot
            bl = cell(length(app.peaks), 2); % Baseline Y points in cell initialization
            if newData == 1 % Check tha box is being checked although this should be unneccesary now
                % Find Gaussian
                delete(app.plotedits) % Remove integration visual plots
                app.plotedits = []; 
                for m = 1: length(vals) % For each gauss...
                    pnum = vals(order(m)); % Ensure going in order of gauss, so get index of gauss number m
                    leftside = app.lims(pnum,1); % Recieve left index of data from PeakLimits finction
                    rightside = app.lims(pnum,2);
                    gauss = fit(app.xdata(leftside:rightside)', app.ydata(leftside:rightside)', 'gauss1'); % Fit the gaussian
                    y = gauss.a1*exp(-((app.xdata-gauss.b1)/gauss.c1).^2); % y points associated w gauss
                    leftgauss = y(1:leftside); % Get gauss points that occur before limits of peak
                    rightgauss = y(rightside:end); % Get gauss points that occur after limits of peak
                    newy = [leftgauss, app.ydata((leftside+1):(rightside-1)), rightgauss]; % y line of peak data between boundarys and gauss data outside boundaries
                    indy = rmoutliers(find(newy > base)); % Indices of all peaks y data above the moving base
                    app.line(pnum, :) = [0, 0]; % Coefficients of linear baseline are zero
                    
                    switch app.VisualizeButtonGroup.SelectedObject.Text
                        case 'Baselines'
                            app.plotedits(pnum,1) = plot(app.IntAx, app.xdata(indy), newy(indy));
        %                         , "Color",app.colors(3,:));
                            hold(app.IntAx, "on") 
                        case 'Areas'
        %                     if mod(pnum, 2) == 0 % alternate colors
        %                         c = app.colors(3,:);
        %                     else 
        %                         c = app.colors(4,:);
        %                     end 
                            app.plotedits(pnum,1) = area(app.IntAx, app.xdata, newy);
        %                         , "FaceColor",c, "EdgeColor", app.colors(1,:));
                    end
                    bl{pnum,1} = app.xdata(indy); % X points of baseline : the xdata of index of all ydata above working base
                    bl{pnum,2} = base(indy); % Y points of baseline : the working baseline corresponding to xdata
                    peakarea = trapz(app.xdata(indy), newy(indy)) - trapz(app.xdata(indy), base(indy)); % Integrate newy data minus integration of baseline
                    base = max(base, newy); % Update the working baseline as top most plots
                    text ={strcat('peak ',num2str(m),' under Gauss: ', num2str(gauss.a1), '*exp(-((x-', num2str(gauss.b1), ')/', num2str(gauss.c1), ')^2)')};  % Write a label for the baseline for the table
                    app.UITable.Data(pnum, 2:end) = [{gauss.b1}, {app.wavelength}, {peakarea}, {gauss.a1}, text]; % populate table
                end

                % Vertical line peaks
                for n = 1:length(app.peaks)
                    if ismember(n,vals)
                        continue % Skip gauss peaks that have already been found
                    end
                    leftside = app.lims(n,1);
                    rightside = app.lims(n,2);
                    % draw lines and visualize
                    app.line(n, :) = [0, 0];
                    switch app.VisualizeButtonGroup.SelectedObject.Text % Determine if plot should show baselines or areas
                        case 'Baselines'
                            app.plotedits(n, 1:2) = plot(app.IntAx, [app.xdata(leftside)  app.xdata(leftside)], [app.ydata(leftside)  base(leftside)], [app.xdata(rightside)  app.xdata(rightside)], [app.ydata(rightside)  base(rightside)]);
    %                         , "Color",app.colors(3,:));
                            hold(app.IntAx, "on")
                        case 'Areas'
    %                         if mod(n, 2) == 0
    %                             c = app.colors(3,:);
    %                         else
    %                             c = app.colors(4,:);
    %                         end
                            app.plotedits(n,1) = area(app.IntAx, app.xdata(leftside:rightside), app.ydata(leftside:rightside));
    %                             , "FaceColor",c, "EdgeColor", app.colors(1,:));
                            hold(app.IntAx, "on")
                    end
                    
    
                    % Integrate
                    bl{n,1} = app.xdata(leftside:rightside); % baseline x point: x points within boundaries
                    bl{n,2} = base(leftside:rightside); % baseline y point: working baseline within boundaries
                    peakarea = trapz(app.xdata(leftside:rightside),app.ydata(leftside:rightside))-trapz(app.xdata(leftside:rightside),base(leftside:rightside)); % peak integration: integration under curve - integration under baseline
                    app.UITable.Data(n,:) = [{false(1)}, {app.pidx(n)}, {app.wavelength}, {peakarea}, {(app.peaks(n)-y(app.xdata == app.pidx(n)))}, {0}]; % update table
                end 
                for m = length(vals):-1:1 % Adjust area plots in order
                    pnum = vals(order(m));
                    uistack(app.plotedits(pnum,1),'top');
                end     
    %                 , "Color",app.colors(3,:));
                app.baselines = bl; % update baseline xy points property
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.IntFig.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IntFig = uifigure('Visible', 'off');
            app.IntFig.Name = 'MATLAB App';
            app.IntFig.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.IntFig);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MinPeakProminanceLabel = uilabel(app.LeftPanel);
            app.MinPeakProminanceLabel.Text = 'Min. Peak Prominance';
            app.MinPeakProminanceSlider = uislider(app.LeftPanel);
            app.MinPeakProminanceSlider.ValueChangedFcn = createCallbackFcn(app, @UpdatePeakPrecision, true);
            app.MinValleyProminanceSlider = uislider(app.LeftPanel);
            app.MinValleyProminanceSlider.ValueChangedFcn = createCallbackFcn(app, @UpdatePeakPrecision, true);
            app.MethodofIntegrationButtonGroup = uibuttongroup(app.LeftPanel);
            app.MethodofIntegrationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IntTypeSelect, true);
            app.MethodofIntegrationButtonGroup.Title = 'Method of Integration';
            app.ValleytoValleyButton = uiradiobutton(app.MethodofIntegrationButtonGroup);
            app.ValleytoValleyButton.Text = 'Valley to Valley';
            app.ValleytoValleyButton.Value = true;
            app.VerticalDropButton = uiradiobutton(app.MethodofIntegrationButtonGroup);
            app.VerticalDropButton.Text = 'Vertical Drop';
            app.MinValleyProminanceSliderLabel = uilabel(app.LeftPanel);
            app.MinValleyProminanceSliderLabel.Text = 'Min. Valley Prominance ';
            app.VisualizeButtonGroup = uibuttongroup(app.LeftPanel);
            app.VisualizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdatePeakPrecision, true);
            app.VisualizeButtonGroup.Title = 'Visualize:';
            app.BaselinesButton = uiradiobutton(app.VisualizeButtonGroup);
            app.BaselinesButton.Text = 'Baselines';
            app.BaselinesButton.Value = true;
            app.AreasButton = uiradiobutton(app.VisualizeButtonGroup);
            app.AreasButton.Text = 'Areas';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.IntAx = uiaxes(app.GridLayout2);
            title(app.IntAx, 'Chromatogram')
            xlabel(app.IntAx, 'Time')
            ylabel(app.IntAx, 'Intensity')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Gauss'; 'Peak Time'; 'At Wavelength'; 'Area'; 'Height'; 'Baseline'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [true false false false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @AddGauss, true);
            app.AddIntegrationstosaveddataButton = uibutton(app.GridLayout2, 'push');
            app.AddIntegrationstosaveddataButton.ButtonPushedFcn = createCallbackFcn(app, @Savedata, true);
            app.AddIntegrationstosaveddataButton.Text = 'Add Integrations to saved data';
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @close, true);
            app.CloseButton.Text = 'Close';
            app.InstructionsLabel = uilabel(app.GridLayout2);
            app.InstructionsLabel.Visible = 'off';
            app.InstructionsLabel.Text = 'Instructions...';
            % Show the figure after all components are created
            app.IntFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PeakIntegrator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IntFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IntFig)
        end
    end
end