classdef Popup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlignPeaksUIFigure         matlab.ui.Figure
        CancelButton               matlab.ui.control.Button
        SelectPeaksButton          matlab.ui.control.Button
        Panel2                     matlab.ui.container.Panel
        ShiftFileDropDown          matlab.ui.control.DropDown
        SelectFileDropDown_2Label  matlab.ui.control.Label
        ShiftDropDown              matlab.ui.control.DropDown
        SelectDetectortoShiftDropDownLabel  matlab.ui.control.Label
        Axshift                    matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        RefFileDropDown            matlab.ui.control.DropDown
        SelectFileDropDownLabel    matlab.ui.control.Label
        RefDropDown                matlab.ui.control.DropDown
        SelecttheReferenceDetectorDropDownLabel  matlab.ui.control.Label
        Axref                      matlab.ui.control.UIAxes
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % HPLCApp

        % Hold vector of all files for each detector
        PDA = {}
        PL = {}
        Conductivity = {}
        RefractiveIndex = {}
        Electrochemical = {}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, HPLCApp)
            app.CallingApp = HPLCApp;

            if ~isempty(app.CallingApp.UITablePDA.DisplayData)
                app.PDA = app.CallingApp.UITablePDA.DisplayData(:,2);
            end
            if ~isempty(app.CallingApp.UITablePL.DisplayData)
                app.PL = app.CallingApp.UITablePL.DisplayData(:,2);
            end
            if ~isempty(app.CallingApp.UITableConductivity.DisplayData)
                app.Conductivity = app.CallingApp.UITableConductivity.DisplayData(:,2);
            end
            if ~isempty(app.CallingApp.UITableRefractiveIndex.DisplayData)
                app.RefractiveIndex = app.CallingApp.UITableRefractiveIndex.DisplayData(:,2);
            end
            if ~isempty(app.CallingApp.UITableElectrochemical.DisplayData)
                app.Electrochemical = app.CallingApp.UITableElectrochemical.DisplayData(:,2);
            end

            app.RefDropDown.Value = '';
            app.ShiftDropDown.Value = '';

        end

        % Value changed function: RefDropDown
        function Refselect(app, event)
            value = app.RefDropDown.Value;

            % Adjust the file select
            app.RefFileDropDown.Items = app.(value);
            app.RefFileDropDown.ItemsData = 1:length(app.(value)); % the values associated with each file

            getax = strcat('UIAxesChrom', app.RefDropDown.Value); % get name of the axis ChromPDA, ChromPL, ...
            num = length(app.CallingApp.(getax).Children) + 1; % the index of the axis is in opposite order of file numbers ( numfile + 1 - fileidx)
            plot(app.Axref, app.CallingApp.(getax).Children(num - app.RefFileDropDown.Value).XData, app.CallingApp.(getax).Children(num - app.RefFileDropDown.Value).YData)

        end

        % Value changed function: ShiftDropDown
        function Shiftselect(app, event)
            value = app.ShiftDropDown.Value;
            
            app.ShiftFileDropDown.Items = app.(value);
            app.ShiftFileDropDown.ItemsData = 1:length(app.(value));

            getax = strcat('UIAxesChrom', app.ShiftDropDown.Value);
            num = length(app.CallingApp.(getax).Children) + 1;
            plot(app.Axshift, app.CallingApp.(getax).Children(num - app.ShiftFileDropDown.Value).XData, app.CallingApp.(getax).Children(num - app.ShiftFileDropDown.Value).YData)
        end

        % Value changed function: RefFileDropDown
        function reffileselect(app, event)
            value = app.RefFileDropDown.Value;
            getax = strcat('UIAxesChrom', app.RefDropDown.Value);
            num = length(app.CallingApp.(getax).Children) + 1; % the index of the axis is in opposite order of file numbers ( numfile + 1 - fileidx)
            plot(app.Axref, app.CallingApp.(getax).Children(num - value).XData, app.CallingApp.(getax).Children(num - value).YData)
        end

        % Value changed function: ShiftFileDropDown
        function shiftfileselect(app, event)
            value = app.ShiftFileDropDown.Value;
            getax = strcat('UIAxesChrom', app.ShiftDropDown.Value);
            num = length(app.CallingApp.(getax).Children) + 1;
            plot(app.Axshift, app.CallingApp.(getax).Children(num - value).XData, app.CallingApp.(getax).Children(num - value).YData)
        end

        % Button pushed function: CancelButton
        function closewin(app, event)
            delete(app)
        end

        % Button pushed function: SelectPeaksButton
        function selectpeaks(app, event)
            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')

            if any([app.RefFileDropDown.Value == 'Select', app.ShiftFileDropDown.Value == 'Select'])
                uialert(app.AlignPeaksUIFigure,'Please select a reference and shifting file', 'Error')
                return
            end
        
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.AlignPeaksUIFigure.HandleVisibility;            % Current status
            app.AlignPeaksUIFigure.HandleVisibility = 'callback';    % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.AlignPeaksUIFigure)           % Make fig current

            temp = app.Label.Text;
            app.Label.Visible = 'off';
            % Reference input
            app.Label.Text = 'First select the reference peak on the left axes';
            app.Label.Visible = 'on';
            tp(1) = drawpoint(app.Axref);
            % Shifting input
            app.Label.Text = 'Now select the shifting peak on the right axes';
            app.Label.Visible = 'on';
            tp(2) = drawpoint(app.Axshift);

            app.Label.Text = temp;

            app.AlignPeaksUIFigure.HandleVisibility = fhv;
            % get the vals
            shft = tp(1).Position(1) - tp(2).Position(1);
            aligningpeaks(app.CallingApp, shft, app.ShiftDropDown.Value, app.ShiftFileDropDown.Value)

            % close the figure
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlignPeaksUIFigure = uifigure('Visible', 'off');
            app.AlignPeaksUIFigure.Name = 'Align Peaks';
            app.Label = uilabel(app.AlignPeaksUIFigure);
            app.Label.Text = 'Select files to shift time:';
            app.Panel = uipanel(app.AlignPeaksUIFigure);
            app.Axref = uiaxes(app.Panel);
            title(app.Axref, 'Reference')
            xlabel(app.Axref, 'Time')
            ylabel(app.Axref, 'Intensity')
            app.SelecttheReferenceDetectorDropDownLabel = uilabel(app.Panel);
            app.SelecttheReferenceDetectorDropDownLabel.Text = 'Select the Reference Detector';
            app.RefDropDown = uidropdown(app.Panel);
            app.RefDropDown.Items = {'', 'PDA', 'PL', 'Conductivity', 'RefractiveIndex', 'Electrochemical'};
            app.RefDropDown.ValueChangedFcn = createCallbackFcn(app, @Refselect, true);
            app.RefDropDown.Placeholder = 'Select';
            app.RefDropDown.Value = 'PDA';
            app.SelectFileDropDownLabel = uilabel(app.Panel);
            app.SelectFileDropDownLabel.Text = 'Select File';
            app.RefFileDropDown = uidropdown(app.Panel);
            app.RefFileDropDown.Items = {'Select'};
            app.RefFileDropDown.ValueChangedFcn = createCallbackFcn(app, @reffileselect, true);
            app.RefFileDropDown.Placeholder = 'Select';
            app.RefFileDropDown.Value = 'Select';
            app.Panel2 = uipanel(app.AlignPeaksUIFigure);
            app.Axshift = uiaxes(app.Panel2);
            title(app.Axshift, 'Shifting')
            xlabel(app.Axshift, 'Time')
            ylabel(app.Axshift, 'Intensity')
            app.SelectDetectortoShiftDropDownLabel = uilabel(app.Panel2);
            app.SelectDetectortoShiftDropDownLabel.Text = 'Select Detector to Shift';
            app.ShiftDropDown = uidropdown(app.Panel2);
            app.ShiftDropDown.Items = {'', 'PDA', 'PL', 'Conductivity', 'RefractiveIndex', 'Electrochemical'};
            app.ShiftDropDown.ValueChangedFcn = createCallbackFcn(app, @Shiftselect, true);
            app.ShiftDropDown.Placeholder = 'Select';
            app.ShiftDropDown.Value = 'PDA';
            app.SelectFileDropDown_2Label = uilabel(app.Panel2);
            app.SelectFileDropDown_2Label.Text = 'Select File';
            app.ShiftFileDropDown = uidropdown(app.Panel2);
            app.ShiftFileDropDown.Items = {'Select'};
            app.ShiftFileDropDown.ValueChangedFcn = createCallbackFcn(app, @shiftfileselect, true);
            app.ShiftFileDropDown.Placeholder = 'Select';
            app.ShiftFileDropDown.Value = 'Select';
            app.SelectPeaksButton = uibutton(app.AlignPeaksUIFigure, 'push');
            app.SelectPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @selectpeaks, true);
            app.SelectPeaksButton.Text = 'Select Peaks';
            app.CancelButton = uibutton(app.AlignPeaksUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @closewin, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.AlignPeaksUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Popup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlignPeaksUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlignPeaksUIFigure)
        end
    end
end